classdef CCG_Editor_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ccg_editor_figure            matlab.ui.Figure
        featureview_pushbutton       matlab.ui.control.Button
        featurethreshold_pushbutton  matlab.ui.control.Button
        gallerymode_checkBox         matlab.ui.control.CheckBox
        masks_pushbutton             matlab.ui.control.Button
        RemovesmalloffcentermasksCheckBox  matlab.ui.control.CheckBox
        groups_pushbutton            matlab.ui.control.Button
        group_coloredit              matlab.ui.control.ColorPicker
        groupnumber_text             matlab.ui.control.Label
        ccg_region_checkbox          matlab.ui.control.CheckBox
        iname_text                   matlab.ui.control.Label
        opath_text                   matlab.ui.control.Label
        editgroup_pushbutton         matlab.ui.control.Button
        editgroup_edit               matlab.ui.control.EditField
        roi_pushbutton               matlab.ui.control.Button
        channel_pushbutton           matlab.ui.control.Button
        save_popupmenu               matlab.ui.control.DropDown
        size_uipanel                 matlab.ui.container.ButtonGroup
        sany_radiobutton             matlab.ui.control.RadioButton
        s256_radiobutton             matlab.ui.control.RadioButton
        s128_radiobutton             matlab.ui.control.RadioButton
        training_pushbutton          matlab.ui.control.Button
        save_pushbutton              matlab.ui.control.Button
        edit_map_pushbutton          matlab.ui.control.Button
        oname_edit                   matlab.ui.control.EditField
        outdir_pushbutton            matlab.ui.control.Button
        showmask_checkbox            matlab.ui.control.CheckBox
        ipath_text                   matlab.ui.control.Label
        source_pushbutton            matlab.ui.control.Button
    end

        % PHSA NON-COMMERCIAL SOURCE-AVAILABLE LICENCE
        % Draft for PHSA review | Prepared September 9, 2026
        % 
        % Software: CCG Editor version 0.9
        % Copyright (c) 2026 Provincial Health Services Authority.
        % All rights reserved.
        % Licensing contact: PHSA Technology Development Office (TDO)
        % tdoadmin@phsa.ca
        % 
        % 1. SCOPE AND ACCEPTANCE
        % 
        % This licence applies to the PHSA-owned source code, executable code, and
        % documentation identified above (the "Software"). Provincial Health Services
        % Authority ("PHSA") grants only the permissions expressly stated here. By using,
        % copying, or modifying the Software, you accept these terms. If acting for an
        % organization, you must have authority to accept them on its behalf.
        % 
        % 2. PERMITTED NON-COMMERCIAL USE
        % 
        % Subject to this licence, PHSA grants a non-exclusive, royalty-free,
        % non-transferable copyright licence to use, copy, and modify the Software:
        % 
        % (a) for internal non-commercial research and education by non-profit
        %     institutions and Canadian federal, provincial, territorial, and local
        %     government bodies; or
        % (b) for internal, non-commercial evaluation by other organizations, solely
        %     to determine whether to seek a separate licence from PHSA.
        % 
        % "Non-commercial" excludes use for commercial advantage, monetary compensation,
        % product or service development for commercial exploitation, paid services,
        % consulting, or work performed for or on behalf of a commercial entity. An
        % organization's non-profit status does not itself make a use non-commercial.
        % 
        % Any other use requires a separate written licence from PHSA through TDO.
        % 
        % 3. COPYING, MODIFICATION, AND REDISTRIBUTION
        % 
        % Copies and modifications must remain within the licensed organization and be
        % used only as permitted above. Preserve this licence, copyright notices, and
        % attributions in every copy; clearly identify modifications and their dates.
        % Do not sell, sublicense, publish, redistribute, or provide third-party access
        % to the Software or modifications without PHSA's prior written permission.
        % 
        % 4. AUTOMATED INGESTION AND AI
        % 
        % Without PHSA's prior written permission, do not submit the Software or its
        % modifications to AI or machine-learning training systems, code-ingestion
        % services, crawlers, indexing services, or text or data mining systems. This
        % restriction concerns ingestion of the Software itself; it does not prohibit
        % ordinary compilation, testing, or execution for a use permitted by section 2.
        % 
        % 5. RESERVED RIGHTS AND THIRD-PARTY MATERIALS
        % 
        % PHSA retains ownership of the Software. No patent or trademark licence is
        % granted. If a proposed use requires patent rights, obtain a separate written
        % licence from PHSA before that use. Separately identified third-party materials
        % remain governed by their own licences; this licence does not replace them.
        % Do not imply PHSA or BC Cancer endorsement or use their logos without written
        % permission.
        % 
        % 6. RESEARCH SOFTWARE; WARRANTY AND LIABILITY
        % 
        % The Software is provided "AS IS", without warranties, including merchantability,
        % fitness for a particular purpose, accuracy, or non-infringement. PHSA has no
        % obligation to support, maintain, or update it. This licence does not authorize
        % clinical diagnosis, treatment, or patient-care decisions.
        % 
        % To the extent permitted by applicable law, PHSA and its personnel are not
        % liable for losses or damages arising from the Software or its use, including
        % lost data, lost profits, or direct, indirect, incidental, or consequential
        % damages, even if advised of their possibility.
        % 
        % 7. TERMINATION AND GOVERNING LAW
        % 
        % Your permissions terminate upon breach. You must then stop using the Software
        % and delete your copies and modifications. Sections concerning reserved rights,
        % warranties, liability, and governing law survive termination. This licence is
        % governed by the laws of British Columbia and the applicable laws of Canada.
        % Revised terms apply only to releases supplied under those revised terms.
        % 
        % END OF LICENCE

    methods (Access = private)
        function [Mimg, Cmask] = CenterMirror(app, handles, img, mask)
            	[Y, X] = size(img);
            
            	C_X2 = floor(X/2);
            	C_Y2 = floor(Y/2);
            
            	[y, x] = ndgrid(1:size(mask, 1), 1:size(mask, 2));
            	centroid = round( mean([x(logical(mask)), y(logical(mask))]) );
            
                offsetX = centroid(1) - C_X2;
                offsetY = centroid(2) - C_Y2;
            
                if(offsetX>0)
                    Mimg = cat(2,...
                        img(:, abs(offsetX)+1:X),...
                        fliplr(img(:, (X-abs(offsetX)+1):X)));
            
                    Cmask = cat(2,...
                        mask(:, abs(offsetX)+1:X),...
                        zeros(Y, abs(offsetX), 'uint8'));
            
                elseif(offsetX<=0)
                    Mimg = cat(2,...
                        fliplr(img(:, 1:abs(offsetX))),...
                        img(:, 1:X-abs(offsetX)));
            
                    Cmask = cat(2,...
                        zeros(Y, abs(offsetX), 'uint8'),...
                        mask(:, 1:X-abs(offsetX)));
                end
            
                if(offsetY>0)
                    Mimg = cat(1,...
                        Mimg(abs(offsetY)+1:Y, :),...
                        flipud(Mimg((Y-abs(offsetY)+1):Y, :)));
            
                    Cmask = cat(1,...
                        Cmask(abs(offsetY)+1:Y, : ),...
                        zeros(abs(offsetY), X, 'uint8') );
                elseif(offsetY<=0)
                    Mimg = cat(1,...
                        flipud(Mimg(1:abs(offsetY), :)),...
                        Mimg(1:Y-abs(offsetY), :));
            
                    Cmask = cat(1,...
                        zeros(abs(offsetY), X, 'uint8'),...
                        Cmask(1:Y-abs(offsetY), : ));
                end
        end
        
        function Editor_CloseRequestFcn(app, hObject, eventdata, handles)
            handles.isEditor = false;
            set(handles.edit_pushbutton, 'String', 'Edit')
            %closereq
            delete(handles.EditorFigHandle)
            %guidata(hObject, handles);
        end
        
        function Preview_CloseRequestFcn(app, hObject, eventdata, handles)
            %Exit functions
            
            handles.isPreview = false;
            set(handles.view_pushbutton, 'String', 'View')
            %closereq
            delete(handles.PreviewFigHandle)
            %delete(hObject)
            %guidata(hObject, handles);
        end

        function [centered_mask, centered_raw, wasCentered] = centerEdit(app, mask, L)
            
            % %Update Center
            centerX = handles.header.vorx(L) - handles.X_Min;
            centerY = handles.header.vory(L) - handles.Y_Min;
            
            [y, x] = ndgrid(1:size(mask, 1), 1:size(mask, 2));
            centroid = round( mean([x(logical(mask)), y(logical(mask))]) );


            % full_center(centerY, centerX) = 0;
            % full_center(centroid(2), centroid(1)) = 255;
        end

        function [isMap] = check_map(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            try
                if(ishandle(handles.MapFigHandle))
                    isMap = true;
                else
                    isMap = false;
                end
            catch
                isMap = false;
            end

        end

        function close_figures(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);

            if(ishandle(handles.MapFigHandle))
                delete(handles.MapFigHandle)
            end

            if(ishandle(handles.ChannelFigHandle))
                delete(handles.ChannelFigHandle)
            end

            if(ishandle(handles.MaskFigHandle))
                delete(handles.MaskFigHandle)
            end

            if(ishandle(handles.GroupFigHandle))
                delete(handles.GroupFigHandle)
            end

            if(ishandle(handles.FeatureFigHandle))
                delete(handles.FeatureFigHandle)
            end

            if(ishandle(handles.FeatureViewFigHandle))
                delete(handles.FeatureViewFigHandle)
            end

        end
        
        function [Screenx, Screeny, vorx, vory, X_Max, Y_Max, X_Min, Y_Min, saveScreenVor] = calcScreenPositions(app, Images, Masks, header, isGallery)
            N = numel(Images);
            saveScreenVor = zeros(N, 4, 'uint32');

            X_Min = min(header.Screenx);
            Y_Min = min(header.Screeny);            

            Screenx = header.Screenx - X_Min;
            Screeny = header.Screeny - Y_Min;
            vorx = header.vorx - X_Min;
            vory = header.vory - Y_Min;

            [Max_Width, X_I] = max(header.Width);
            [Max_Height, Y_I] = max(header.Height);            

            R = Max_Width / Max_Height;

            X_Max = max(Screenx + header.Width);
            Y_Max = max(Screeny + header.Height);

            saveScreenVor(:, 1) = Screenx;
            saveScreenVor(:, 2) = Screeny;
            saveScreenVor(:, 3) = vorx;            
            saveScreenVor(:, 4) = vory;

            if(isGallery)
                nScreenx = Screenx;
                nScreeny = Screeny;

                numX = ceil(sqrt(double(N)/(double(R)^2)));
                numY = ceil(double(N)/numX);
                
                Dvorx = vorx - Screenx;
                Dvory = vory - Screeny;

                for n=1:N

                    nScreenx(n) = 1 + mod( (n - 1), numX ) * Max_Width;
                    nScreeny(n) = 1 + floor( (n - 1) / numX) * Max_Height;

                end

                Screenx = nScreenx;
                Screeny = nScreeny;
                vorx = nScreenx + Dvorx;
                vory = nScreeny + Dvory;

                X_Max = max(Screenx + Max_Width);
                Y_Max = max(Screeny + Max_Height);
            end


        end
        function [DATA_frame, DATA_logical, dMasks] = createFullData(app,Images,Masks,N,Screenx,Screeny,X_Max,Y_Max,MaxImages)
            isBackgroundDark = getBackground(app, Images);
            SE = strel('square',3);

            %DATA_frame = ones(Y_Max-Y_Min, X_Max-X_Min, MaxImages, 'uint8')*255;
            %DATA_logical = zeros(Y_Max-Y_Min, X_Max-X_Min, 'uint8');
            if(isBackgroundDark)
                DATA_frame = zeros(Y_Max, X_Max, MaxImages, 'uint8');
            else
                DATA_frame = ones(Y_Max, X_Max, MaxImages, 'uint8')*255;
            end
            
            DATA_logical = zeros(Y_Max, X_Max, 'uint8');
            for n=1:N
                % posx = Screenx(n)-X_Min;
                % posy = Screeny(n)-Y_Min;
                posx = Screenx(n);
                posy = Screeny(n);
                [Y, X, Z] = size(Images{n});

                for z=1:Z
                    DATA_frame(posy+1:posy+Y, posx+1:posx+X, z) = Images{n}(:,:,z);
                end
                DATA_logical(posy+1:posy+Y, posx+1:posx+X) = ones(Y,X,'uint8');
                %dMasks{n} = imdilate(Masks{n}, SE) - Masks{n};
                %dMasks{n} = Masks{n} - imerode(Masks{n}, SE);
                [mY,mX,mZ] = size(Masks{n});
                for mz=1:mZ
                    dMasks{n}(:,:,mz) = Masks{n}(:,:,mz) - uint8( bwmorph(Masks{n}(:,:,mz), 'erode') );
                end
            end

        end
        function [RGB_raw] = createFullImage(app, Images, full_data, full_logical, colormap,X_Max,Y_Max,MaxImages)

            %isBackgroundDark = getBackground(app, Images);
            %isBackgroundDark = 1;
            %RGB_raw = zeros(Y_Max-Y_Min, X_Max-X_Min, 3, 'single');
            RGB_raw = zeros(Y_Max, X_Max, 3, 'single');
            
            for i=1:3
                %img = zeros(Y_Max-Y_Min, X_Max-X_Min, 'single');
                img = zeros(Y_Max, X_Max, 'single');
                for j=1:MaxImages
                    if(colormap(j, 5))
                        img = img + ( single( full_data(:,:,j) ) * colormap(j, i) ) * (1/colormap(j, 4));
                    end
                end

                RGB_raw(:, :, i) = img.*single(full_logical);
                % if(isBackgroundDark)
                % 
                % else
                %     RGB_raw(:, :, i) = RGB_raw(:, :, i) + single(imcomplement(full_logical*255));
                % end
            end
                %RGB_raw = (RGB_raw ./ prctile(RGB_raw(:), 99.9)) * 255;
                RGB_raw = uint8(RGB_raw);
        end
        function [RGB_mask, full_bitmask, full_center] = createFullMask(app,full_raw,GroupColor,maskColor,Masks,dMasks,N,Screenx,Screeny,vorx,vory,Group,X_Max,Y_Max,maskNum)
            %Creates full sized mask from CMG Mask data

            % full_bitmask = zeros(Y_Max-Y_Min, X_Max-X_Min, 'uint8');
            % full_center = zeros(Y_Max-Y_Min, X_Max-X_Min, 'uint8');
            % 
            % centerX = vorx - X_Min + 1;
            % centerY = vory - Y_Min + 1;
            % 
            % posx = Screenx-X_Min;
            % posy = Screeny-Y_Min;
            M = numel(maskNum);

            full_bitmask = zeros(Y_Max, X_Max, M, 'uint8');
            full_center = zeros(Y_Max, X_Max, 'uint8');

            centerX = vorx + 1;
            centerY = vory + 1;

            posx = Screenx;
            posy = Screeny;
            
            for m=1:M
                if(maskNum(m))
                    for n=1:N
                        [Y, X, ~] = size(Masks{n});
                        try
                            if( GroupColor( (Group(n)+1) ,4) )
                                full_bitmask(posy(n)+1:posy(n)+Y, posx(n)+1:posx(n)+X, m) = full_bitmask(posy(n)+1:posy(n)+Y, posx(n)+1:posx(n)+X, m) - (dMasks{n}(:,:,m)*255);
                                full_bitmask(posy(n)+1:posy(n)+Y, posx(n)+1:posx(n)+X, m) = full_bitmask(posy(n)+1:posy(n)+Y, posx(n)+1:posx(n)+X, m) + (dMasks{n}(:,:,m)*(Group(n)+1));
                            end
                            try
                                full_center(centerY(n), centerX(n)) = 255;
                            catch
                                disp('WARNING: Unable to place center position. Possible vorx, vory missing.')
                            end
                        catch
                            disp('')
                        end
                    end
                end
            end
            RGB_mask = makeRGBmap(app, full_raw, full_bitmask, GroupColor, maskColor);

        end
        function [RGB_mask] = createFullThreshMask(app, full_raw, full_bitmask, Masks,dMasks,Screenx,Screeny,X_Max,Y_Max,isThresh,maskNum,plusColor,negColor, Accession)
            posx = Screenx;
            posy = Screeny;

            full_bitmask( find(full_bitmask > 0) ) = 255;
            for m=1:numel(maskNum)
                if(maskNum(m))
                    full_bitmask(:,:,1) = full_bitmask(:,:,1) + full_bitmask(:,:,m);
                end
            end
            full_bitmask = full_bitmask(:,:,1);
            

            full_bitmask_pos = zeros(Y_Max, X_Max, 'uint8');
            for m=1:numel(maskNum)
                if(maskNum(m))            
                    for n=1:numel(Accession)
                        [Y, X, ~] = size(Masks{n});
                        if(isThresh(Accession(n)))
                            try
                                full_bitmask_pos(posy(n)+1:posy(n)+Y, posx(n)+1:posx(n)+X) = full_bitmask_pos(posy(n)+1:posy(n)+Y, posx(n)+1:posx(n)+X) + (dMasks{n}(:,:,m)*255);
                            catch
                            end
                        end
                    end
                end
            end

            %RED
            RGB_mask(:,:,1) = full_raw(:,:,1) - full_bitmask;
            RGB_mask(:,:,1) = RGB_mask(:,:,1) + negColor(1)*full_bitmask;
            RGB_mask(:,:,1) = RGB_mask(:,:,1) - full_bitmask_pos;
            RGB_mask(:,:,1) = RGB_mask(:,:,1) + plusColor(1)*full_bitmask_pos;

            %Green
            RGB_mask(:,:,2) = full_raw(:,:,2) - full_bitmask;
            RGB_mask(:,:,2) = RGB_mask(:,:,2) + negColor(2)*full_bitmask;
            RGB_mask(:,:,2) = RGB_mask(:,:,2) - full_bitmask_pos;
            RGB_mask(:,:,2) = RGB_mask(:,:,2) + plusColor(2)*full_bitmask_pos;

            %Blue
            RGB_mask(:,:,3) = full_raw(:,:,3) - full_bitmask;
            RGB_mask(:,:,3) = RGB_mask(:,:,3) + negColor(3)*full_bitmask;
            RGB_mask(:,:,3) = RGB_mask(:,:,3) - full_bitmask_pos;
            RGB_mask(:,:,3) = RGB_mask(:,:,3) + plusColor(3)*full_bitmask_pos;

        end
        
        function L = findCells(app, hObject, eventdata, handles, position)
            %Find Cells
            
            
            top = floor(position(2));
            bottom = floor(position(2)) + ceil(position(4));
            left = floor(position(1));
            right = floor(position(1)) + ceil(position(3));
            
            xp = handles.header.vorx - handles.X_Min;
            yp = handles.header.vory - handles.Y_Min;
            
            xrange = find(xp >= left & xp <= right);
            yrange = find(yp >= top & yp <= bottom);
            
            L = ismember(xrange, yrange);
            L = L .* xrange;
            L(L==0) = [];
            
            % for l=1:numel(L)
            %
            %     mask = handles.Masks{L(l)};
            %     maskH = sum(mask, 2);
            %     maskW = sum(mask, 1);
            %
            %     maskH(maskH ~= 0) = 1;
            %     maskW(maskW ~= 0) = 1;
            %
            %     EmaskH = edge(maskH);
            %     EmaskW = edge(maskW);
            %
            %     EmaskH = EmaskH' .* [1:1:numel(EmaskH)];
            %     EmaskW = EmaskW .* [1:1:numel(EmaskW)];
            %
            %     EmaskH = nonzeros(EmaskH);
            %     EmaskW = nonzeros(EmaskW);
            %
            %     EmaskH(2) = EmaskH(2)-1;
            %     EmaskW(2) = EmaskW(2)-1;
            %
            %     EmaskH = EmaskH - (size(mask, 1)/2) + double(yp(L(l)));
            %     EmaskW = EmaskW - (size(mask, 2)/2) + double(xp(L(l)));
            %
            %     if(top >= (EmaskH(1)-3))
            %         L(l)=0;
            %     end
            %     if(bottom <= (EmaskH(2)+3))
            %         L(l)=0;
            %     end
            %     if(left >= (EmaskW(1)-3))
            %         L(l)=0;
            %     end
            %     if(right <= (EmaskW(2)+3))
            %         L(l)=0;
            %     end
            % end
            
                L = nonzeros(L);
        end
        
        function [Sheader, SImages, SMasks] = get256Nuclei(app, handles)
                Sheader = handles.header;
                %Generate full channel images
                %colorChannels = max(handles.header.NbColorMap);
                %maskChannels = max(handles.header.NbBitMap);
                [fY, fX, fZ] = size(handles.full_data);
            
                frame = zeros(fY+256, fX+256, fZ, 'uint8');%*255;
                frame( 129:end-128, 129:end-128 , : ) = handles.full_data;
                % for n=1:handles.N
                %     posx = handles.header.Screenx(n)-handles.X_Min+128;
                %     posy = handles.header.Screeny(n)-handles.Y_Min+128;
                %     [Y, X, Z] = size(handles.Images{n});
                % 
                %     frame(posy+1:posy+Y, posx+1:posx+X, :) = handles.Images{n};
                % 
                % end
            
                %Mirror edges
                for i=1:fZ
                    frame(1:128, :, i) = flip(frame(129:256, :, i));
                    frame(end-127:end, :, i) = flip(frame(end-255:end-128, :, i));
            
                    frame(:, 1:128, i) = fliplr(frame(:, 129:256, i));
                    frame(:, end-127:end, i) = fliplr(frame(:, end-255:end-128, i));
                end
            
                %Crop each Nuclei
                for n=1:handles.N
                    [Y, X, Z] = size(handles.Images{n});
            
                    nScreenx = handles.header.vorx(n)-handles.X_Min+128-127;%%+128 from mirror -127 from offset
                    nScreeny = handles.header.vory(n)-handles.Y_Min+128-127;
            
                    maskSx = handles.header.Screenx(n)-handles.X_Min+128;%%+128 from mirror
                    maskSy = handles.header.Screeny(n)-handles.Y_Min+128;
            
                    img = frame((nScreeny+1):(nScreeny+256), (nScreenx+1):(nScreenx+256), :);
                    [Y, X, Z] = size(handles.Masks{n});
                    fullmask = zeros(fY+256,fX+256,Z,'uint8');
                    fullmask((maskSy+1):(maskSy+Y), (maskSx+1):(maskSx+X), :) = handles.Masks{n};
                    mask = fullmask((nScreeny+1):(nScreeny+256), (nScreenx+1):(nScreenx+256), :);
            
                    SImages{n} = img;
                    SMasks{n} = mask;
            
                    Screenx(n) = double(handles.header.vorx(n)) - 127;
                    Screeny(n) = double(handles.header.vory(n)) - 127;
                    vorx(n) = double(handles.header.vorx(n));
                    vory(n) = double(handles.header.vory(n));
                    Sheader.Width(n) = 256;
                    Sheader.Height(n) = 256;
            
            %         if(Y < 256 && X < 256)
            %             x2 = floor((256 - X)/2);
            %             y2 = floor((256 - Y)/2);
            %
            %             aposx = handles.header.Screenx(n)-handles.X_Min+128-x2;
            %             aposy = handles.header.Screeny(n)-handles.Y_Min+128-y2;
            %
            %             img = frame(aposy+1:aposy+256, aposx+1:aposx+256, :);
            %
            %             mask = zeros(256,256,maskChannels,'uint8');
            %             mask(y2+1:y2+Y, x2+1:x2+X, :) = handles.Masks{n};
            %
            %             SImages{n} = img;
            %             SMasks{n} = mask;
            %
            %             Screenx(n) = double(handles.header.Screenx(n)) - x2;
            %             Screeny(n) = double(handles.header.Screeny(n)) - y2;
            %             vorx(n) = double(handles.header.vorx(n));
            %             vory(n) = double(handles.header.vory(n));
            %             Sheader.Width(n) = 256;
            %             Sheader.Height(n) = 256;
            %         else
            %             SImages{n} = handles.Images{n};
            %             SMasks{n} = handles.Masks{n};
            %             Screenx(n) = double(handles.header.Screenx(n));
            %             Screeny(n) = double(handles.header.Screeny(n));
            %             vorx(n) = double(handles.header.vorx(n));
            %             vory(n) = double(handles.header.vory(n));
            %         end
                end
            
                min_x = min(Screenx);
                min_y = min(Screeny);
                %vmin_x = min(vorx);
                %vmin_y = min(vory);
            
                Screenx = Screenx - (min_x);
                Screeny = Screeny - (min_y);
                vorx = vorx - (min_x);
                vory = vory - (min_y);
            
                Sheader.Screenx = uint32(Screenx);
                Sheader.Screeny = uint32(Screeny);
                Sheader.vorx = uint32(vorx);
                Sheader.vory = uint32(vory);
        end
        
        function full_bitmask = getMaskFrame(app, hObject, eventdata, handles)
            
            full_bitmask = zeros(handles.Y_Max, handles.X_Max, 'uint8');
            for n=1:handles.N
                posx = handles.header.Screenx(n)-handles.X_Min;
                posy = handles.header.Screeny(n)-handles.Y_Min;
            %    raw_full(posy+1:posy+handles.Y, posx+1:posx+handles.X) = handles.Images{n};
                mask_stack = handles.Masks{n};
                try
                    [Y, X, Z] = size(mask_stack);
                    mask = mask_stack(:,:,handles.maskNum);
                    full_bitmask(posy+1:posy+Y, posx+1:posx+X) = full_bitmask(posy+1:posy+Y, posx+1:posx+X) + (mask*255);
                catch
                    disp('WARNING: Mask missing')
                end
            clear mask
            end
            guidata(hObject, handles);
        end
        
        function full_selectedmask = getSelectedMask(app, hObject, eventdata, handles, L)
            
            full_bitmask = zeros(handles.Y_Max, handles.X_Max, 'uint8');
            SE = strel('square',3);
            
            for n=1:numel(L)
            
                posx = handles.header.Screenx(L(n))-handles.X_Min;
                posy = handles.header.Screeny(L(n))-handles.Y_Min;
                mask = imdilate(handles.Masks{L(n)}, SE) - handles.Masks{L(n)};
                full_bitmask( posy+1:posy+size( handles.Masks{L(n)}, 1 ), posx+1:posx+size( handles.Masks{L(n)}, 2 ) ) = full_bitmask( posy+1:posy+size( handles.Masks{L(n)}, 1 ), posx+1:posx+size( handles.Masks{L(n)}, 2 )) + mask(:,:,handles.maskNum)*255;
            end
            full_selectedmask = handles.full_mask;
            full_selectedmask(:,:,3) = full_selectedmask(:,:,3) - full_bitmask;
            full_selectedmask(:,:,2) = full_selectedmask(:,:,2) - full_bitmask;
            full_selectedmask(:,:,1) = full_selectedmask(:,:,1) - full_bitmask;
            
            full_selectedmask(:,:,3) = full_selectedmask(:,:,3) + (full_bitmask-150);
            full_selectedmask(:,:,2) = full_selectedmask(:,:,2) + full_bitmask;%makes orange
            full_selectedmask(:,:,1) = full_selectedmask(:,:,1) + (full_bitmask-105);
        end
        
        function [Sheader, SImages, SMasks] = getSeperateNuclei(app, handles)
            L = zeros(handles.N, 1);
            full_bitmask = zeros(handles.Y_Max, handles.X_Max, 'uint8');
            for n=1:handles.N
                posx = handles.header.Screenx(n)-handles.X_Min;
                posy = handles.header.Screeny(n)-handles.Y_Min;
                mask = handles.Masks{n}(:,:,handles.maskNum);
                [Y, X] = size(mask);
                full_bitmask(posy+1:posy+Y, posx+1:posx+X) = full_bitmask(posy+1:posy+Y, posx+1:posx+X) + mask;
            end
            
            for n=1:handles.N
                full_Sbitmask = zeros(handles.Y_Max, handles.X_Max, 'uint8');
                posx = handles.header.Screenx(n)-handles.X_Min;
                posy = handles.header.Screeny(n)-handles.Y_Min;
                mask = handles.Masks{n}(:,:,handles.maskNum);
                [Y, X] = size(mask);
                full_Sbitmask(posy+1:posy+Y, posx+1:posx+X) = full_Sbitmask(posy+1:posy+Y, posx+1:posx+X) + mask;
                full_Xbitmask = full_bitmask.*full_Sbitmask;
                isOverlap=0;
                if(max(max(full_Xbitmask))>=2)
                    isOverlap=1;
                end
            
                if(~isOverlap)
                    L(n) = n;
                end
            
            end
            
                L = nonzeros(L);
            
            for l=1:numel(L)
                    SImages(l) = handles.Images(L(l));
                    SMasks(l) = handles.Masks(L(l));
            end
            
            Sheader = makeNewHeader(app, handles, L, [0 0], 0);
        end
        
        function N_header = makeNewHeader(app, handles, L, position, isROI)
            
            for i=1:numel(L)
            
                N_header.Mode(i) = handles.header.Mode(L(i));
                N_header.NbColorMap(i) = handles.header.NbColorMap(L(i));
                N_header.Class(i) = handles.header.Class(L(i));
                if(isROI)
                    N_header.Screenx(i) = uint32(0);%handles.header.Screenx(L(i));
                    N_header.Screeny(i) = uint32(0);%handles.header.Screeny(L(i));
                else
                    N_header.Screenx(i) = handles.header.Screenx(L(i));
                    N_header.Screeny(i) = handles.header.Screeny(L(i));
                end
                N_header.Stagex(i) = uint64(handles.header.Screenx(L(i)));
                N_header.Stagey(i) = uint64(handles.header.Screeny(L(i)));
                N_header.Stagez(i) = handles.header.Stagez(L(i));
                N_header.Resolution(i) = handles.header.Resolution(L(i));
                N_header.LowThreshold(i) = handles.header.LowThreshold(L(i));
                N_header.MidThreshold(i) = handles.header.MidThreshold(L(i));
                N_header.Group(i) = handles.header.Group(L(i));
                N_header.Width(i) = handles.header.Width(L(i));
                N_header.Height(i) = handles.header.Height(L(i));
                %N_header.Accession(i) = handles.header.Accession(L(i));
                N_header.Iod(i) = handles.header.Iod(L(i));
                N_header.Fluor(i) = handles.header.Fluor(L(i));
                N_header.Diagnosis(i) = handles.header.Diagnosis(L(i));
                N_header.RedFaction(i) = handles.header.RedFaction(L(i));
                N_header.GreenFaction(i) = handles.header.GreenFaction(L(i));
                N_header.BlueFaction(i) = handles.header.BlueFaction(L(i));
                N_header.Index(i) = handles.header.Index(L(i));
                N_header.Objective(i) = handles.header.Objective(L(i));
                N_header.Calibrated(i) = handles.header.Calibrated(L(i));
                N_header.StackX_int(i) = handles.header.StackX_int(L(i));
                N_header.StackY_int(i) = handles.header.StackY_int(L(i));
                N_header.NbBitMap(i) = handles.header.NbBitMap(L(i));
                N_header.CassettePosition(i) = handles.header.CassettePosition(L(i));
                if(isROI)
                    N_header.vorx(i) = handles.header.vorx(L(i)) - position(1) - handles.X_Min;
                    N_header.vory(i) = handles.header.vory(L(i)) - position(2) - handles.Y_Min;
                else
                    N_header.vorx(i) = handles.header.vorx(L(i));
                    N_header.vory(i) = handles.header.vory(L(i));
                end
                N_header.BestFocusFrame(i) = handles.header.BestFocusFrame(L(i));
                N_header.BackgroundFloat(i) = handles.header.BackgroundFloat(L(i));
                N_header.PrimaryColourChannel(i) = handles.header.PrimaryColourChannel(L(i));
                N_header.Layer(i) = handles.header.Layer(L(i));
                N_header.Points(i) = handles.header.Points(L(i));
                N_header.NumFeatures(i) = handles.header.NumFeatures(L(i));
                N_header.RGB_Order(i) = handles.header.RGB_Order(L(i));
            
            end
            
            N_header.Accession = uint32([1:1:numel(L)]);
        end
        
        function full_mask = makeRGBmap(app, full_raw, full_bitmask, GroupColor, maskColor)
            [Y,X,M]=size(full_bitmask);
                lutR = uint8( cat(1, 0, GroupColor(1:(end-1), 1) ) * 255);
                lutG = uint8( cat(1, 0, GroupColor(1:(end-1), 2) ) * 255);
                lutB = uint8( cat(1, 0, GroupColor(1:(end-1), 3) ) * 255);
                full_mask = full_raw;
            for m=1:M
                    full_mask(:,:,1) = (full_mask(:,:,1) - full_bitmask(:,:,m)*255);
                    full_mask(:,:,2) = (full_mask(:,:,2) - full_bitmask(:,:,m)*255);
                    full_mask(:,:,3) = (full_mask(:,:,3) - full_bitmask(:,:,m)*255);                
                if(maskColor(m,4))
                    full_mask(:,:,1) = (full_bitmask(:,:,m)*255)*maskColor(m,1) + full_mask(:,:,1);
                    full_mask(:,:,2) = (full_bitmask(:,:,m)*255)*maskColor(m,2) + full_mask(:,:,2);
                    full_mask(:,:,3) = (full_bitmask(:,:,m)*255)*maskColor(m,3) + full_mask(:,:,3);
                else
                    full_mask(:,:,1) = intlut(full_bitmask(:,:,m), lutR) + full_mask(:,:,1);
                    full_mask(:,:,2) = intlut(full_bitmask(:,:,m), lutG) + full_mask(:,:,2);
                    full_mask(:,:,3) = intlut(full_bitmask(:,:,m), lutB) + full_mask(:,:,3);
                end
            end

        end
        
        function [Image, Mask, ScreenX, ScreenY, vorx, vory] = newNuclei(app, handles, mask)
            
            if(get(handles.s128_radiobutton,'Value')==1)
                Xsel = 128;
                Ysel = 128;
            elseif(get(handles.s256_radiobutton,'Value')==1)
                Xsel = 256;
                Ysel = 256;
            elseif(get(handles.sany_radiobutton,'Value')==1)
                [Ysel, Xsel, ~] = size(handles.Images{handles.currentNumber});
            end

            % if(handles.SelectionX==0 && handles.SelectionY==0)
            %     [Ysel, Xsel, Z] = size(handles.Images{handles.currentNumber});
            % else
            %     Xsel = handles.SelectionX;
            %     Ysel = handles.SelectionY;
            % end
            
            Xsel2 = Xsel/2;
            Ysel2 = Ysel/2;
            
            [Y, X] = size(mask);
            
            
            [y, x] = ndgrid(1:size(mask, 1), 1:size(mask, 2));
            centroid = round( mean([x(logical(mask)), y(logical(mask))]) );
            NposX = centroid(1);
            NposY = centroid(2);
            
            L = NposX - (Xsel2-1);
            R = NposX + Xsel2;
            T = NposY - (Ysel2-1);
            B = NposY + Ysel2;
            
            
            if(B > Y)
                T = Y - (Ysel-1);
                B = Y;
            end
            if(R > X)
                L = X - (Xsel-1);
                R = X;
            end
            if(T < 1)
                T = 1;
                B = Ysel;
            end
            if(L < 1)
                L = 1;
                R = Xsel;
            end
            
            Image = handles.full_data( T:B , L:R , : );
            Mask = mask( T:B, L:R);
            
            ScreenX = L -1;
            ScreenY = T -1;
            
            vorx = NposX;
            vory = NposY;
        end
        
        function handles = repeatCloseEditor(app, event, handles)
            handles.isEditor = false;
            delete(handles.EditorFigHandle)
            edit_pushbutton_Callback(app, event);
            guidata(hObject, handles);
        end
        
        function restoreAccession(app, hObject, eventdata, handles)
            
            L = numel(handles.header.Accession);
            handles.header.Accession = uint32([1:1:L]);
            guidata(hObject, handles);
        end
        
        function [handles, X, Y] = setNucleiImage(app, hObject, eventdata, handles)
            %Helper functions
            
            %Creates display image/mask for current img/mask
                img = handles.Images{handles.currentNumber};
                mask = handles.Masks{handles.currentNumber};
                mask = mask(:,:,handles.maskNum);
                [Y, X, Z] = size(img);
            
                X2 = floor(X/2);
                Y2 = floor(Y/2);
                M = 1;
            
                %Add crosshair at center
                img( Y2 , X2-M:X2+M , :) = 0;
                img( Y2-M :Y2+M , X2 , :) = 0;
                mask( Y2 , X2-M:X2+M) = 0;
                mask( Y2-M :Y2+M , X2 ) = 0;
            
                R = zeros(Y, X, 'uint8');
                G = zeros(Y, X, 'uint8');
                B = zeros(Y, X, 'uint8');
                for n=1:size(handles.colormap, 1)
                    if(handles.colormap(n,1)==1)
                        R = R + img(:,:,n);
                    end
                    if(handles.colormap(n,2)==1)
                        G = G + img(:,:,n);
                    end
                    if(handles.colormap(n,3)==1)
                        B = B + img(:,:,n);
                    end
                end
                R = uint8( single(R) / sum(handles.colormap(:,1)) );
                G = uint8( single(G) / sum(handles.colormap(:,2)) );
                B = uint8( single(B) / sum(handles.colormap(:,3)) );
                handles.display_img = cat(3, R, G, B);
                handles.mask_img = cat(3, R + mask*255, G, B);
            
                guidata(hObject, handles);
        end
        
        function update_Editor(app, hObject, eventdata, handles)
                if(handles.isEditor)
                    isMask = get(handles.showmask_checkbox,'Value');
                    img = handles.Images{handles.currentNumber};
                    mask = handles.Masks{handles.currentNumber};
                    mask = mask(:,:,handles.maskNum);
                    [Y, X, Z] = size(img);
            
                    X2 = floor(X/2);
                    Y2 = floor(Y/2);
                    M = 1;
            
                    img( Y2 , X2-M:X2+M , :) = 0;
                    img( Y2-M :Y2+M , X2 , : ) = 0;
                    mask( Y2 , X2-M:X2+M) = 0;
                    mask( Y2-M :Y2+M , X2 ) = 0;
            
                    R = zeros(Y, X, 'uint8');
                    G = zeros(Y, X, 'uint8');
                    B = zeros(Y, X, 'uint8');
                    for n=1:size(handles.colormap, 1)
                        if(handles.colormap(n,1)==1)
                            R = R + img(:,:,n);
                        end
                        if(handles.colormap(n,2)==1)
                            G = G + img(:,:,n);
                        end
                        if(handles.colormap(n,3)==1)
                            B = B + img(:,:,n);
                        end
                    end
                    R = uint8( single(R) / sum(handles.colormap(:,1)) );
                    G = uint8( single(G) / sum(handles.colormap(:,2)) );
                    B = uint8( single(B) / sum(handles.colormap(:,3)) );
                    handles.display_img = cat(3, R, G, B);
                    handles.mask_img = cat(3, R + mask*255, G, B);
            
                    %handles.display_img = cat(3, img, img, img);
                    %handles.mask_img = cat(3, img + mask*255, img, img);
            %         if(Z==1)
            %             handles.display_img = cat(3, img, img, img);
            %             handles.mask_img = cat(3, img + mask*255, img, img);
            %         elseif(Z==3)
            %             handles.display_img = img;
            %             mask_img = img;
            %             mask_img(:,:,1) = mask_img(:,:,1) + mask*255;
            %             handles.mask_img = mask_img;
            %             clear mask_img
            %         end
                    guidata(hObject, handles);
            
                    if(isMask)
                        set(handles.EditorFig, 'CData', handles.mask_img)
                    else
                        set(handles.EditorFig, 'CData', handles.display_img)
                    end
            
                end
                guidata(hObject, handles);
        end
        
        function update_Map(app, event)
                [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
                if(ishandle(handles.MapFigHandle))
                    %isMask = get(handles.showmask_checkbox,'Value');
                    if(handles.isMask)
                        set(handles.MapFig, 'CData', handles.full_mask)
                    else
                        set(handles.MapFig, 'CData', handles.full_raw)
                    end
                end
                guidata(hObject, handles);
        end
        
        function update_Preview(app, hObject, eventdata, handles)
                if(handles.isPreview)
                    isMask = get(handles.showmask_checkbox,'Value');
                    img = handles.Images{handles.currentNumber};
                    mask = handles.Masks{handles.currentNumber};
                    mask = mask(:,:,handles.maskNum);
                    [Y, X, Z] = size(img);
            
                    X2 = floor(X/2);
                    Y2 = floor(Y/2);
                    M = 1;
            
                    img( Y2 , X2-M:X2+M) = 0;
                    img( Y2-M :Y2+M , X2 ) = 0;
                    mask( Y2 , X2-M:X2+M) = 0;
                    mask( Y2-M :Y2+M , X2 ) = 0;
            
            
                    R = zeros(Y, X, 'uint8');
                    G = zeros(Y, X, 'uint8');
                    B = zeros(Y, X, 'uint8');
                    for n=1:size(handles.colormap, 1)
                        if(handles.colormap(n,1)==1)
                            R = R + img(:,:,n);
                        end
                        if(handles.colormap(n,2)==1)
                            G = G + img(:,:,n);
                        end
                        if(handles.colormap(n,3)==1)
                            B = B + img(:,:,n);
                        end
                    end
                    R = R / sum(handles.colormap(:,1));
                    G = G / sum(handles.colormap(:,2));
                    B = B / sum(handles.colormap(:,3));
                    handles.display_img = cat(3, R, G, B);
                    handles.mask_img = cat(3, R + mask*255, G, B);
            %         handles.display_img = cat(3, img, img, img);
            %         handles.mask_img = cat(3, img + mask*255, img, img);
            
                    if(isMask)
                        set(handles.PreviewFig, 'CData', handles.mask_img)
                    else
                        set(handles.PreviewFig, 'CData', handles.display_img)
                    end
            
                end
                guidata(hObject, handles);
        end
        
        function [Images, Masks, header] = removeBlankMaskCells(app, Images, Masks, header)
            N = numel(Masks);

            for n=1:N
                m = N-(n-1);
                removeObj = 0;
                sumtotal = sum( sum( Masks{m}(:,:,1) ) );
                centerpixel = Masks{m}( floor( header.Height(m)/2 ), floor( header.Width(m)/2 ), 1 );

                if( (sumtotal <= 10) || (centerpixel == 0) )
                    removeObj = 1;
                end

                if( removeObj )
                    Images(m) = [];
                    Masks(m) = [];

                    header.Mode(m) = [];
                    header.NbColorMap(m) = [];
                    header.Class(m) = [];
                    header.Screenx(m) = [];
                    header.Screeny(m) = [];
                    header.Stagex(m) = [];
                    header.Stagey(m) = [];
                    header.Stagez(m) = [];
                    header.Resolution(m) = [];
                    header.LowThreshold(m) = [];
                    header.MidThreshold(m) = [];
                    header.Group(m) = [];
                    header.Width(m) = [];
                    header.Height(m) = [];
                    header.Accession(m) = [];
                    header.Iod(m) = [];
                    header.Fluor(m) = [];
                    header.Diagnosis(m) = [];
                    header.RedFaction(m) = [];
                    header.GreenFaction(m) = [];
                    header.BlueFaction(m) = [];
                    header.Index(m) = [];
                    header.Objective(m) = [];
                    header.Calibrated(m) = [];
                    header.StackX_int(m) = [];
                    header.StackY_int(m) = [];
                    header.NbBitMap(m) = [];
                    header.CassettePosition(m) = [];
                    header.vorx(m) = [];
                    header.vory(m) = [];
                    header.BestFocusFrame(m) = [];
                    header.BackgroundFloat(m) = [];
                    header.PrimaryColourChannel(m) = [];
                    header.Layer(m) = [];
                    header.Points(m) = [];
                    header.NumFeatures(m) = [];
                    header.RGB_Order(m) = [];

                end
            
            end
            L = numel(header.Accession);
            header.Accession = uint32([1:1:L]);                
        end

        function isBackgroundDark = getBackground(app, Images)
            
            N=numel(Images);
            if(N>100)
                M = 100;
            else
                M = N;
            end
            borderpixels = [];
            for m=1:M
                borderpixels = cat(2, borderpixels, Images{m}(1,:,1));
            end
            
            mpixels = mean(borderpixels);

            if(mpixels >= 128)
                isBackgroundDark = 0;
            else
                isBackgroundDark = 1;
            end

        end
    
        function Masks = checkMasks(app, Masks)
            M = numel(Masks);
            for m=1:M
                [Y, X, Z] = size(Masks{m});
                for z=1:Z
                    if( max(max(Masks{m}(:,:,z))) > 1 )
                        Masks{m}(:,:,z) = Masks{m}(:,:,z)/255;
                    end
                end
            end
        end

        function isThresh = getThresholdCells(app, fV, T)

            N = numel(fV);
            isThresh_min = zeros(N , 1, 'logical');
            isThresh_max = zeros(N , 1, 'logical');
            isThresh = zeros(N , 1, 'logical');

            isThresh_min(  find(fV>=T(1) )  ) = 1;
            isThresh_max(  find(fV<=T(2) )  ) = 1;

            isThresh = isThresh_min.*isThresh_max;

        end
    
        function [MinMax, cThresh] = readThreshExcell(app, path, name, F)
            file = [path, name, '.xlsx'];
            
            cThresh = readmatrix(file, 'Sheet', 1, 'Range', ['A2:B', num2str(F+1)]);
            MinMax = readmatrix(file, 'Sheet', 1, 'Range', ['C2:E', num2str(F+1)]);
        end

        function writeThreshExcell(app, path, name, MinMax, cThresh)
            splname = strsplit(name, '.');
            filename = [splname{1}, '.xlsx'];
            
            writecell({'Lower Value'}, filename, 'Sheet', 1, 'Range', 'A1')
            writecell({'Upper Value'}, filename, 'Sheet', 1, 'Range', 'B1')
            writecell({'Min Value'}, filename, 'Sheet', 1, 'Range', 'C1')
            writecell({'Max Value'}, filename, 'Sheet', 1, 'Range', 'D1')
            writecell({'Valid Value'}, filename, 'Sheet', 1, 'Range', 'E1')
            
            writematrix(cThresh, filename, 'Sheet', 1, 'Range', 'A2')
            writematrix(MinMax, filename, 'Sheet', 1, 'Range', 'C2')
            
            movefile(filename, path);
        end

        function BusyHandle = startBusy(app, msg1, msg2)
            pos = get(app.ccg_editor_figure, 'Position');
            BusyHandle = figure('Position',[pos(1)+200 pos(2)+110,200,80],'Color',[0.9204,0.9087,0.8424],'MenuBar','none');
            uicontrol('Style','text','String',msg1,'Position',[20,45,160,25], 'ForegroundColor',[0 0 0],'BackgroundColor',[0.9204,0.9087,0.8424]);
            uicontrol('Style','text','String',msg2,'Position',[20,15,160,25], 'ForegroundColor',[0 0 0],'BackgroundColor',[0.9204,0.9087,0.8424]);
            pause(0.1)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CMG_Editor_OpeningFcn(app, varargin)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app);
            res = get(0,'screensize');
            resX = res(3);
            resY = res(4);
            clear res

            %Ensure that the app appears on screen when run
            %movegui(app.ccg_editor_figure, 'onscreen');

            %Define Editor window size and position
            H = 300;
            W = 600;
            set(app.ccg_editor_figure, 'Position', [50 50 W H])            
            
            try
                load('ccgeditor_groupcolors.mat', 'groupcolor')
                handles.GroupColor = groupcolor;
            catch
                handles.GroupColor = zeros(256, 4);
                handles.GroupColor(:, 4) = ones(1, 256);
                handles.GroupColor(1:6, :) = [ 1 0 0 1; 0 0 1 1; 0 1 0 1; 1 1 0 1; 0 1 1 1; 1 0 1 1];
            end
            handles.GroupColor(255, :) = [0 0 0 1];


            handles.output = hObject;
            handles.isSaved = true;
            handles.currentNumber = 1;
            handles.N = 0;
            handles.X = 0;
            handles.Y = 0;

            %Flags for which figures are currently open
            handles.BusyHandle=[];
            handles.MapFigHandle=[];
            handles.ChannelFigHandle=[];
            handles.MaskFigHandle=[];
            handles.GroupFigHandle=[];
            handles.FeatureFigHandle=[];
            handles.FeatureViewFigHandle=[];


            handles.SelectionX = 256;
            handles.SelectionY = 256;
            
            handles.cmgpath = '';
            
            handles.isMask = true;
            handles.saveMode = 0;
            handles.isGallery = 0;
            
            handles.firstview = true;
            handles.XLim = [];
            handles.YLim = [];
            handles.position = [];
            handles.maskNum = uint8(1);
            handles.isFeatures = false;
            
            handles.isEditRepeat = 0;


            guidata(hObject, handles);
        end

        % Close request function: ccg_editor_figure
        function ccg_editor_figure_CloseRequestFcn(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if(~handles.isSaved)
                %CMG/CCG is NOT saved
                msg = ['File ', handles.cmgname, ' has unsaved changes!'];
                title = 'Confirm Exit';
                selection = uiconfirm(app.ccg_editor_figure,msg,title, ...
                    "Options",["Exit","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);

                if(strcmp( selection, 'Exit' ))
                    close_figures(app, event)
                    delete(hObject);
                else
                    %Don't close
                end
            else
                %CMG/CCG is saved so just close
                close_figures(app, event)
                delete(hObject);
            end


        end

        % Button pushed function: source_pushbutton
        function source_pushbutton_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            res = get(0,'screensize');
            resX = res(3);
            resY = res(4);
            minres = min(resX, resY);
            clear res           

            %------------------------Push Buttons--------------------------------------
            %CMG fuction
            isload = 1;


            if(~handles.isSaved)
                %  f = warndlg('Masks are unsaved. Press the Save CMG button to save.','Unsaved CMG...');
                % waitfor(f)
                msg = ['File ', handles.cmgname, ' has unsaved changes!'];
                title = 'Confirm change source.';
                selection = uiconfirm(app.ccg_editor_figure,msg,title, ...
                    "Options",["Continue","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);
                if(strcmp( selection, 'Continue' ))
                    isload = 1;
                else
                    isload = 0;
                end

            end


            if(isload)


                try
                    load('ccgeditor_inpath.mat', 'cmgpath')
                catch
                    cmgpath = 'C:\';
                end

                [cmgname, cmgpath] = uigetfile({'*.cmg;*.ccg'}, 'Select CMG/CCG', cmgpath);


                if(ischar(cmgname) && ischar(cmgpath))
                    save('ccgeditor_inpath.mat', 'cmgpath')
                    handles.cmgpath = cmgpath;
                    handles.cmgname = cmgname;
                    handles.opath = handles.cmgpath;
                    handles.oname = handles.cmgname;

                    handles.cmgname = strsplit(handles.cmgname, '.');
                    handles.filetype = handles.cmgname{end};
                    Tstr = handles.cmgname{1};
                    for n=1:(numel(handles.cmgname)-2)
                        Tstr = [Tstr, '.', handles.cmgname{n+1}];
                    end
                    handles.cmgname = Tstr;
                    
                    set(handles.ipath_text,'String',['Initializing...', cmgname])

                    handles.BusyHandle = startBusy(app, 'Initializing...', cmgname);

                    if(exist( [handles.cmgpath,'\',handles.cmgname,'.cb4'], 'file' ) == 2)
                        [ features ] = readCB4( handles.cmgpath, handles.cmgname);
                        handles.featureLabels = features(:, 1);
                        handles.F = numel(handles.featureLabels);
                        handles.featureEnum = 1:1:handles.F;
                        %handles.featureIsThresh = zeros(handles.F, 1, 'logical');
                        handles.featureData = cell2mat( features(:, 2:end) );
                        handles.featureActive = 1;
                        
                        handles.featureMinMax = zeros(handles.F, 3);
                        handles.featureCurrentThresh = zeros(handles.F, 2);

                        if(exist( [handles.cmgpath,'\',handles.cmgname,'.xlsx'], 'file' ) == 2)
                                [handles.featureMinMax, handles.featureCurrentThresh] = readThreshExcell(app, handles.cmgpath, handles.cmgname, handles.F);
                        else
                            for f=1:handles.F
                                Lmin = floor( double( min( handles.featureData(f, :) ) ) );
                                Lmax = ceil( double( max( handles.featureData(f, :) ) ) );

                                handles.featureMinMax(f,1) = Lmin;
                                handles.featureMinMax(f,2) = Lmax;
                                handles.featureCurrentThresh(f,1) = Lmin;
                                handles.featureCurrentThresh(f,2) = Lmax;

                                if( (Lmax > Lmin) && ~isinf(Lmin) && ~isinf(Lmax) && ~isnan(Lmin) &&  ~isnan(Lmax) )
                                    handles.featureMinMax(f,3) = 1;
                                else
                                    handles.featureMinMax(f, 3) = 0;
                                end
                            end
                        end

                        % app.features_table.Data = handles.featureLabels(1)';
                        % app.features_table.Data(numel(handles.featureLabels),1) = handles.featureLabels(1)';
                        % app.features_table.Data(:,1) = handles.featureLabels';
                        % app.features_table.Data(:,2) = num2cell(handles.featureData(:, 1));

                        set(handles.featurethreshold_pushbutton, 'Enable', 'on')
                        set(handles.featureview_pushbutton, 'Enable', 'on')
                        handles.isFeatures = true;
                    else
                        set(handles.featurethreshold_pushbutton, 'Enable', 'off')
                        set(handles.featureview_pushbutton, 'Enable', 'off')
                        handles.isFeatures = false;
                    end


                    if( strcmp(handles.filetype, 'ccg') )
                        if(app.ccg_region_checkbox.Value)
                            [ handles.Images, handles.Masks, handles.header, handles.ImageLabel, handles.MaskLabel, handles.ilist ] = readCCG_ROI( handles.cmgpath, handles.cmgname, 0 );
                        else
                            [ handles.Images, handles.Masks, handles.header, handles.ImageLabel, handles.MaskLabel, handles.ilist ] = readCCG( handles.cmgpath, handles.cmgname );
                        end
                    elseif( strcmp(handles.filetype, 'cmg') )
                        [ handles.Images, handles.Masks, handles.header ] = readCMG( handles.cmgpath, handles.cmgname );
                        handles.ImageLabel=[];
                        handles.MaskLabel=[];
                        handles.ilist=[];
                    end
                    
                    handles.header.Accession = uint32(1:1:numel(handles.header.Accession));
                    handles.Masks = checkMasks(app, handles.Masks);

                    handles.N = numel(handles.Images);
                    [handles.Y, handles.X, handles.Z] = size(handles.Images{1});

                    handles.MaxImages = max(handles.header.NbColorMap);
                    handles.colormap = zeros(handles.MaxImages, 5, 'single');
                    switch handles.MaxImages
                        case 1
                            handles.colormap(1, 1:5) = 1;
                        case 2
                            handles.colormap(1, 1:5) = 1;
                        otherwise
                            handles.colormap(1, 1) = 1;
                            handles.colormap(2, 2) = 1;
                            handles.colormap(3, 3) = 1;
                            handles.colormap(1:3, 4:5) = 1;
                    end

                    try
                        matsave = ['ccgeditor_channelcolors_',num2str(handles.MaxImages),'.mat'];
                        load(matsave, 'colormap')
                        if(size(colormap,1) == handles.MaxImages)
                            handles.colormap = colormap;
                        end

                    catch

                    end
                    
                    handles.MaxMasks = max(handles.header.NbBitMap);

                    % [X_Max, X_I] = max(handles.header.Screenx + handles.header.Width);
                    % [Y_Max, Y_I] = max(handles.header.Screeny + handles.header.Height);
                    % handles.X_Max = X_Max;
                    % handles.Y_Max = Y_Max;
                    % handles.X_Min = min(handles.header.Screenx);
                    % handles.Y_Min = min(handles.header.Screeny);
                    handles.firstview = true;
                    handles.maskNum = zeros(handles.MaxMasks, 1, 'logical');
                    handles.maskNum(1) = 1;
                    handles.maskColor = zeros(handles.MaxMasks, 5);
                    
                    try
                        matsave = ['ccgeditor_maskcolors_',num2str(handles.MaxMasks),'.mat'];
                        load(matsave, 'maskcolor')
                        if(size(maskcolor,1) == handles.MaxMasks)
                            handles.maskColor = maskcolor;
                        end

                    catch

                    end
                    handles.maskColor(:, 5) = 1;

                    guidata(hObject, handles);

                    [handles.Screenx, handles.Screeny, handles.vorx, handles.vory, handles.X_Max, handles.Y_Max, handles.X_Min, handles.Y_Min, handles.saveScreenVor] = calcScreenPositions(app, handles.Images, handles.Masks, handles.header, handles.isGallery);

                    [handles.full_data, handles.full_logical, handles.dMasks] = createFullData(app,handles.Images,handles.Masks,handles.N,handles.Screenx,handles.Screeny,handles.X_Max,handles.Y_Max,handles.MaxImages);
                    [handles.full_raw] = createFullImage(app, handles.Images, handles.full_data, handles.full_logical, handles.colormap,handles.X_Max,handles.Y_Max,handles.MaxImages);
                    [handles.full_mask, handles.full_bitmask, handles.full_center] = createFullMask(app,handles.full_raw,handles.GroupColor, handles.maskColor, handles.Masks,handles.dMasks,handles.N,handles.Screenx,handles.Screeny,handles.vorx,handles.vory,handles.header.Group,handles.X_Max,handles.Y_Max,handles.maskNum);

                    set(handles.ipath_text,'String',handles.cmgpath)
                    set(handles.iname_text,'String',[handles.cmgname,'.',handles.filetype])
                    set(handles.outdir_pushbutton, 'Enable', 'on')
                    set(handles.opath_text,'String',handles.cmgpath)
                    set(handles.oname_edit,'String',[handles.cmgname,'.',handles.filetype])
                    set(handles.oname_edit, 'Enable', 'on')

                    %set(handles.nuclei_edit, 'String', double2str(1))
                    handles.currentNumber = 1;
                    %set(handles.add_nuclei_pushbutton, 'Enable', 'on')
                    %set(handles.remove_nuclei_pushbutton, 'Enable', 'on')
                    set(handles.roi_pushbutton, 'Enable', 'on')
                    
                    set(handles.training_pushbutton, 'Enable', 'on')
                    set(handles.edit_map_pushbutton, 'Enable', 'on')
                    set(handles.showmask_checkbox, 'Enable', 'on')
                    set(handles.checkmasks_checkbox, 'Enable', 'on')
                    set(handles.save_pushbutton, 'Enable', 'on')
                    set(handles.editgroup_pushbutton, 'Enable', 'on')
                    set(handles.editgroup_edit, 'Enable', 'on')
                    set(handles.channel_pushbutton, 'Enable', 'on')
                    set(handles.masks_pushbutton, 'Enable', 'on')
                    set(handles.groups_pushbutton, 'Enable', 'on')
                    set(handles.group_coloredit, 'Enable', 'on')
                    set(handles.group_coloredit, 'Value', handles.GroupColor(1, 1:3))

                    for m=1:handles.MaxMasks
                        str{m} = num2str(m);
                    end
                    if(ishandle(handles.BusyHandle))
                        delete(handles.BusyHandle)
                    end
                end
                handles.isSaved = true;
            end
            %figure, imshow(handles.full_bitmask*255)
            guidata(hObject, handles);

        end

        % Button pushed function: outdir_pushbutton
        function outdir_pushbutton_Callback(app, event)
            %Output functions
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                try
                    load('cmgeditor_outpath.mat', 'opath')
                catch
                    opath = 'C:\';
                end
            
                opath = uigetdir(opath);
                if(ischar(opath))
                    save('cmgeditor_outpath.mat', 'opath')
                end
            
                handles.opath = opath;
                set(handles.opath_text,'String',handles.opath)
                guidata(hObject, handles);
        end

        % Value changed function: oname_edit
        function oname_editValueChanged(app, event)
            value = app.oname_edit.Value;
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            handles.oname = value;
            guidata(hObject, handles);
        end

        % Value changed function: save_popupmenu
        function save_popupmenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            get(handles.save_popupmenu, 'Value');
            if( get(handles.save_popupmenu, 'Value') == 1 )
                handles.saveMode = 0;
            elseif( get(handles.save_popupmenu, 'Value') ==2 )
                handles.saveMode = 1;
            elseif( get(handles.save_popupmenu, 'Value') ==3 )
                handles.saveMode = 2;
            end
            guidata(hObject, handles);
        end

        % Button pushed function: save_pushbutton
        function save_pushbutton_Callback(app, event)
            %CMG save function
            res = get(0,'screensize');
            resX = res(3);
            resY = res(4);
            clear res            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);


            doSave = 1;
            if(isfile([handles.opath, handles.oname]))
                msg = ['File ', handles.oname, ' already exists! Overwrite it?'];
                title = 'Confirm Overwrite';
                selection = uiconfirm(app.ccg_editor_figure,msg,title, ...
                    "Options",["Overwrite","Cancel"], ...
                    "DefaultOption",2,"CancelOption",2);
                if(strcmp( selection, 'Overwrite' ))
                    doSave = 1;
                    delete([handles.opath, handles.oname])
                else
                    doSave = 0;
                end

            end


            if(doSave)

                saving = figure('Position',[(resX/2)-(200/2) (resY/2)-(80/2),200,80],'Color',[0.9204,0.9087,0.8424],'MenuBar','none');
                uicontrol('Style','text','String','SAVING.....','Position',[20,40,100,25]);
                movegui(saving,'center');

                filenamesplit = get(handles.oname_edit, 'String');
                filenamesplit = strsplit(filenamesplit, '.');
                fileformat = filenamesplit{end};
                filename = '';
                for n=1:(numel(filenamesplit)-1)
                    filename = [filename, filenamesplit{n}];
                end

                % handles.header.Screenx = handles.Screenx;
                % handles.header.Screeny = handles.Screeny;
                % handles.header.vorx = handles.vorx;
                % handles.header.vory = handles.vory;

                handles.header.Screenx = handles.saveScreenVor(:, 1);
                handles.header.Screeny = handles.saveScreenVor(:, 2);
                handles.header.vorx = handles.saveScreenVor(:, 3);
                handles.header.vory = handles.saveScreenVor(:, 4);

                if(handles.saveMode == 0)

                    SImages = handles.Images;
                    SMasks = handles.Masks;
                    Sheader = handles.header;

                    L = handles.MaxMasks;
                    for l=1:L
                        ll = L - (l - 1);
                        if( ~handles.maskColor(ll, 5) )
                            SMasks = cellfun(@x, SMasks, 'uni', false);
                        end
                    end
                    % for n=1:N
                    %     L = size( SMasks{n}, 3 );
                    %     for l=1:L
                    %         ll = L - (l - 1);
                    %         if( ~handles.maskColor(ll, 5) )
                    %             SMasks{n}(:,:,ll) = [];
                    %         end
                    %     end
                    % end

                    if( get(handles.checkmasks_checkbox, 'Value') == 1 )
                        [SImages, SMasks, Sheader] = removeBlankMaskCells(app, handles.Images, SMasks, handles.header);
                        %[SImages, SMasks, Sheader] = removeBlankMaskCells(app, handles.Images, handles.Masks, handles.header);
                    end

                    if(strcmp(fileformat, 'cmg'))
                        writeCMG( Sheader, SImages, SMasks, [handles.opath,'\'], filename )
                    elseif(strcmp(fileformat, 'ccg'))
                        writeCCG( Sheader, 0, 0, SImages, SMasks, [handles.opath,'\'], filename )
                    else
                        disp(['Invalid save format!'])
                    end 
                    handles.isSaved = true;
                elseif(handles.saveMode == 1)
                    [Sheader, SImages, SMasks] = getSeperateNuclei(app, handles);
                    if(strcmp(fileformat, 'cmg'))
                        writeCMG( Sheader, SImages, SMasks, [handles.opath,'\'], filename )
                    elseif(strcmp(fileformat, 'ccg'))
                        writeCCG( Sheader, 0, SImages, SMasks, [handles.opath,'\'], filename )
                    else
                        disp(['Invalid save format!'])
                    end
                    clear Sheader SImages SMasks
                elseif(handles.saveMode == 2)
                    [Sheader, SImages, SMasks] = get256Nuclei(app, handles);
                    if(strcmp(fileformat, 'cmg'))
                        writeCMG( Sheader, SImages, SMasks, [handles.opath,'\'], filename )
                    elseif(strcmp(fileformat, 'ccg'))
                        writeCCG( Sheader, 0, SImages, SMasks, [handles.opath,'\'], filename )
                    else
                        disp(['Invalid save format!'])
                    end
                    clear Sheader SImages SMasks
                    %handles.isSaved = true;
                end
                
                % full_bitmask = getMaskFrame(app, hObject, eventdata, handles);
                % RGB(:,:,1) = full_bitmask*255;
                % RGB(:,:,2) = handles.full_data(:,:,1);
                % RGB(:,:,3) = handles.full_data(:,:,1);
                % 
                % imwrite(RGB, [handles.opath,'\', filename,'_Map.TIFF'])
                
                delete(saving);
            end
            guidata(hObject, handles);

            function SMasks = x(SMasks)
                SMasks(:,:,ll) = [];
            end
        end

        % Button pushed function: roi_pushbutton
        function roi_pushbutton_Callback(app, event)
            res = get(0,'screensize');
            resX = res(3);
            resY = res(4);
            minres = min(resX, resY);
            clear res
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);

            if(~ishandle(handles.MapFigHandle))

                handles.MapFigHandle = figure('Name', 'CMG Map');
                set(gca,'units','pixels'); % set the axes units to pixels
                x = get(gca,'position'); % get the position of the axes
                set(gcf,'units','pixels'); % set the figure units to pixels
                y = get(gcf,'position'); % get the figure position
                set(gcf,'position',[y(1) y(2) x(3) x(4)])% set the position of the figure to the length and width of the axes
                set(gca,'units','normalized','position',[0 0 1 1]) % set the axes units to pixels
                set(gcf, 'OuterPosition', [(resX/2-minres/2) (resY/2-minres/2) minres minres])
                %set(gcf, 'CloseRequestFcn', @(hObject,eventdata)CMG_Editor('Map_CloseRequestFcn',hObject,eventdata,handles))
                h = zoom(handles.MapFigHandle);
                h.ActionPostCallback = @zoomMapAspect;
                set(gcf, 'ResizeFcn', @resize_fcn);

                handles.isMap = true;

                %handles.currentNumber = str2double(get(handles.nuclei_edit,'String'));


                %[handles.full_bitmask, handles.full_center, handles.full_mask] = createFullMask(hObject, eventdata, handles, handles.full_raw);
                handles.MapFig = imshow(handles.full_mask);
                [Y, X, ~] = size(handles.full_mask);

                ROIobj_basal = drawpolyline('Color','red');
                ROIobj_external = drawpolyline('Color','blue');

                if( (numel(ROIobj_external.Position) > 0) && (numel(ROIobj_basal.Position) > 0) )
                    header = [7 0 0 0 X Y 0 0 0 0 1 1 20 33 0];
                else
                    header = [5 0 0 0 X Y 0 0 0 0 1 1 20 33 0];
                end

                writeROI( handles.cmgpath, handles.cmgname, header, ROIobj_basal.Position, ROIobj_external.Position, [] )
                disp('Done.')

                close(handles.MapFigHandle)


            end

            guidata(hObject, handles);

            function resize_fcn(varargin)
                checkRatio
            end

            function zoomMapAspect(varargin)
                checkRatio
            end

            function checkRatio
                w_pos = get(gcf, 'position');
                ratioXY = w_pos(4)/w_pos(3);
                ratioYX = w_pos(3)/w_pos(4);
                xlim = get(gca, 'XLim');
                ylim = get(gca, 'YLim');
                xlen = xlim(2)-xlim(1);
                ylen = ylim(2)-ylim(1);
                if( xlen > ylen )
                    s_ylen = xlen*ratioXY;
                    diff = s_ylen - ylen;
                    set(gca, 'YLim', [ylim(1)-(diff/2)  ylim(2)+(diff/2)])
                elseif( ylen > xlen )
                    s_xlen = ylen*ratioYX;
                    diff = s_xlen - xlen;
                    set(gca, 'XLim', [xlim(1)-(diff/2)  xlim(2)+(diff/2)])
                end
            end
        end

        % Value changed function: editgroup_edit
        function editgroup_edit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            value = uint8(str2double(app.editgroup_edit.Value));

            try
                str = get(handles.editgroup_edit, 'String');
                Snum = str2double(str);

                if(Snum>255)
                    set(handles.editgroup_edit, 'String', '255');
                elseif(Snum<0)
                    set(handles.editgroup_edit, 'String', '0');
                elseif(isnan(Snum))
                    set(handles.editgroup_edit, 'String', '0');
                else
                    set(handles.editgroup_edit, 'String', num2str(floor(Snum)))
                end

            catch
                set(handles.editgroup_edit, 'String', '0');
            end

            set(handles.group_coloredit, 'Value', handles.GroupColor(value+1, 1:3))

        end

        % Callback function: group_coloredit
        function group_coloreditValueChanged(app, event)
            res = get(0,'screensize');
            resX = res(3);
            resY = res(4);
            clear res  
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            value = app.group_coloredit.Value;

            pos = get(app.ccg_editor_figure, 'Position');
            loading = figure('Position',[pos(1)+200 pos(2)+110,200,80],'Color',[0.9204,0.9087,0.8424],'MenuBar','none');
            uicontrol('Style','text','String','WORKING.....','Position',[20,40,100,25]);
            movegui(loading,'center');

            activegroupnum = uint8( str2double( get(handles.editgroup_edit, 'String') )+1 );
            handles.GroupColor(activegroupnum, 1:3) = value;
            handles.isSaved = false;
            guidata(hObject, handles);
            [handles.full_mask, ~, ~] = createFullMask(app,handles.full_raw,handles.GroupColor, handles.maskColor, handles.Masks,handles.dMasks,handles.N,handles.Screenx,handles.Screeny,handles.vorx,handles.vory,handles.header.Group,handles.X_Max,handles.Y_Max,handles.maskNum);
            %[~,~,handles.full_mask] = createFullMask(app, hObject, handles);
            guidata(hObject, handles);
            update_Map(app, event);
            groupcolor = handles.GroupColor;
            save('ccgeditor_groupcolors.mat', 'groupcolor')
            delete(loading);
            %guidata(hObject, handles);
        end

        % Button pushed function: editgroup_pushbutton
        function editgroup_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                res = get(0,'screensize');
                resX = res(3);
                resY = res(4);
                minres = min(resX, resY);
                clear res
            
            
                if(~ishandle(handles.MapFigHandle))
            
                    handles.MapFigHandle = figure('Name', 'CMG Map');
                    set(gca,'units','pixels'); % set the axes units to pixels
                    x = get(gca,'position'); % get the position of the axes
                    set(gcf,'units','pixels'); % set the figure units to pixels
                    y = get(gcf,'position'); % get the figure position
                    set(gcf,'position',[y(1) y(2) x(3) x(4)])% set the position of the figure to the length and width of the axes
                    set(gca,'units','normalized','position',[0 0 1 1]) % set the axes units to pixels
                    set(gcf, 'OuterPosition', [(resX/2-minres/2) (resY/2-minres/2) minres minres])
                    %set(gcf, 'CloseRequestFcn', @(hObject,eventdata)CMG_Editor('Map_CloseRequestFcn',hObject,eventdata,handles))
                    h = zoom(handles.MapFigHandle);
                    h.ActionPostCallback = @zoomMapAspect;
                    set(gcf, 'ResizeFcn', @resize_fcn);
            
                    handles.isMap = true;
            
                    isMask = get(handles.showmask_checkbox,'Value');
            
            
                    %[handles.full_bitmask, handles.full_center, handles.full_mask] = createFullMask(hObject, eventdata, handles, handles.full_raw);
                    handles.MapFig = imshow(handles.full_mask);
                    [Y, X, ~] = size(handles.full_mask);
                    try
                        isDone = false;
                        while(~isDone)
            
                            freehand=drawfreehand(gca);
                            wait(freehand);
            
                            Sgroup = get(handles.editgroup_edit, 'String');
                            Ngroup = str2double(Sgroup);
            
                            xp = double(handles.vorx);
                            yp = double(handles.vory);
            
                            inclosed_cells = inROI(freehand,xp,yp);
                            for n=1:numel(inclosed_cells)
                                if(inclosed_cells(n))
                                    handles.header.Group(n) = Ngroup;
                                end
                            end
                            [handles.full_mask, handles.full_bitmask, handles.full_center] = createFullMask(app,handles.full_raw,handles.GroupColor, handles.maskColor, handles.Masks,handles.dMasks,handles.N,handles.Screenx,handles.Screeny,handles.vorx,handles.vory,handles.header.Group,handles.X_Max,handles.Y_Max,handles.maskNum);
                            %[handles.full_bitmask, handles.full_center, handles.full_mask] = createFullMask(app, event);
            
                            set(handles.MapFig, 'CDATA', handles.full_mask)
                            delete(freehand)
                            guidata(hObject, handles);
                        end
                    catch
            
                    end
                end


                function resize_fcn(varargin)
                    checkRatio     
                end
            
                function zoomMapAspect(varargin)
                    checkRatio
                end
            
                function checkRatio
                    w_pos = get(gcf, 'position');
                    ratioXY = w_pos(4)/w_pos(3);
                    ratioYX = w_pos(3)/w_pos(4);
                    xlim = get(gca, 'XLim');
                    ylim = get(gca, 'YLim');
                    xlen = xlim(2)-xlim(1);
                    ylen = ylim(2)-ylim(1);
                    if( xlen > ylen )
                        s_ylen = xlen*ratioXY;
                        diff = s_ylen - ylen;
                        set(gca, 'YLim', [ylim(1)-(diff/2)  ylim(2)+(diff/2)])
                    elseif( ylen > xlen )
                        s_xlen = ylen*ratioYX;
                        diff = s_xlen - xlen;
                        set(gca, 'XLim', [xlim(1)-(diff/2)  xlim(2)+(diff/2)])
                    end
                end
        end

        % Selection changed function: size_uipanel
        function size_uipanel_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if(get(handles.s128_radiobutton,'Value')==1)
                handles.SelectionX = 128;
                handles.SelectionY = 128;
            elseif(get(handles.s256_radiobutton,'Value')==1)
                handles.SelectionX = 256;
                handles.SelectionY = 256;
            elseif(get(handles.sany_radiobutton,'Value')==1)
                handles.SelectionX = 0;
                handles.SelectionY = 0;
            end
            guidata(hObject, handles);
        end

        % Button pushed function: featurethreshold_pushbutton
        function featurethreshold_pushbuttonButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            res = get(0,'screensize');
            resX = res(3);
            resY = res(4);
            clear res

            if(ishandle(handles.FeatureFigHandle))
                figClose(handles.FeatureFigHandle)
            else
                w = 400;
                h = 750;
                wb = 10;
                hb = 20;
                handles.FeatureFigHandle = uifigure('Name','Feature Threshold','Position', [resX-w 60 w h]);
                handles.FeatureFigHandle.CloseRequestFcn = @(src,event)figClose(src);
    
                enum = handles.featureActive;
                Lmin = double( min( handles.featureData(enum, :) ) );
                Lmax = double( max( handles.featureData(enum, :) ) );
    
                Lmin = floor( Lmin );
                Lmax = ceil( Lmax );
    
                feature_table = uitable( handles.FeatureFigHandle,...
                    "SelectionChangedFcn",@(src,event) featureChanged(src, event)... 
                    );
                feature_table.Tag = "feature_table";
                feature_table.Position = [wb hb+300 (w-(2*wb)) h-(hb)-300];
                feature_table.ColumnName = ["Feature","Value"];
                feature_table.Data = handles.featureLabels(1)';
                feature_table.Data(numel(handles.featureLabels),1) = handles.featureLabels(1)';
                feature_table.Data(:,1) = handles.featureLabels';
                feature_table.Data(:,2) = num2cell(handles.featureData(:, 1));
                feature_table.SelectionType = "row";
                feature_table.Multiselect = "off";
    
    
                feature_hist = uiaxes(handles.FeatureFigHandle);
                feature_hist.Position = [wb hb+90 (w-(2*wb)) 200];
                feature_hist.XTick = [];
                feature_hist.YTick = [];
                %fhist = histcounts(handles.featureData(1, :), 100);
                fhist = histcounts(handles.featureData(1, :), 'Normalization', 'probability');
                plot(feature_hist, fhist)
    
    
                feature_editfield_min = uieditfield( handles.FeatureFigHandle,...
                    "numeric",...
                    'Position', [ wb wb 50 30 ],...
                    "ValueChangedFcn",@(src,event) editfieldChanged(src));
                feature_editfield_max = uieditfield( handles.FeatureFigHandle,...
                    "numeric",...
                    'Position', [ w-wb-50 wb 50 30 ],...
                    "ValueChangedFcn",@(src,event) editfieldChanged(src));
    
                feature_editfield_min.Value = handles.featureCurrentThresh(enum, 1);
                feature_editfield_max.Value = handles.featureCurrentThresh(enum, 2);

                colorpicker_pos = uicolorpicker(handles.FeatureFigHandle,...
                    'Position',[140 wb 50 20],...
                    'Value',[1 0 0],...
                    "ValueChangedFcn",@(src,event)colorChanged(src) );
                colorpicker_neg = uicolorpicker(handles.FeatureFigHandle,...
                    'Position',[210 wb 50 20],...
                    'Value',[0 0 0],...
                    "ValueChangedFcn",@(src,event)colorChanged(src) );

                feature_slider = uislider( handles.FeatureFigHandle, "range",...
                    "Orientation","horizontal",...
                    'Position',[wb+5 hb+60 w-2*(wb+5) 3],...
                    "ValueChangedFcn",@(src,event) sliderChanged(src));
                guidata(hObject, handles);
                changeFeature(enum)
    
                handles.featureIsThresh = getThresholdCells(app, handles.featureData(enum, :), [handles.featureCurrentThresh(enum, 1) handles.featureCurrentThresh(enum, 2)]);
                handles.plusColorThresh = colorpicker_pos.Value;
                handles.negColorThresh = colorpicker_neg.Value;

                app.featurethreshold_pushbutton.Text = 'CLOSE';
                guidata(hObject, handles);
                updateThresh()

            end


            function featureChanged(src, event)
                %enum = src.Value;
                enum = event.Selection;
                changeFeature(enum)
            end

            function changeFeature(enum)
                handles = guidata(hObject);
                %fhist = histcounts(handles.featureData(enum, :), 100);
                fhist = histcounts(handles.featureData(enum, :), 'Normalization', 'probability');
                plot(feature_hist, fhist)

                Lmin = handles.featureMinMax(enum, 1);
                Lmax = handles.featureMinMax(enum, 2);
                cLmin = handles.featureCurrentThresh(enum, 1);
                cLmax = handles.featureCurrentThresh(enum, 2);

                if( handles.featureMinMax(enum, 3) )
                    feature_slider.Enable = 1;
                    feature_editfield_min.Enable = 1;
                    feature_editfield_max.Enable = 1;

                    feature_slider.Limits = [Lmin, Lmax];
                    feature_slider.Value = [cLmin cLmax];
                    majorTicks = [Lmin:((Lmax-Lmin)/9):Lmax];
                    feature_slider.MajorTicks = majorTicks;
                    for n=1:numel(majorTicks)
                        majorTickLabels{n} = num2str( majorTicks(n));
                    end
                    feature_slider.MajorTickLabels = majorTickLabels;
                    %feature_slider.MinorTicks = [Lmin:((Lmax-Lmin)/100):Lmax];
                    feature_slider.MinorTicks = [];
                    %feature_slider.Step = 1 / (Lmax - Lmin);
                    %Step = feature_slider.Step
    
                    feature_editfield_min.Value = cLmin;
                    feature_editfield_max.Value = cLmax;

                    handles.featureIsThresh = getThresholdCells(app, handles.featureData(enum, :), [cLmin cLmax]);
                    guidata(hObject, handles);
                    updateThresh()

                else
                    disp(['WARNING: ', handles.featureLabels{enum}' ,' has invalid feature limits. [min: ',num2str(Lmin),', max: ',num2str(Lmax),']' ])
                    feature_slider.Enable = 0;
                    feature_editfield_min.Enable = 0;
                    feature_editfield_max.Enable = 0;
                end
                handles.featureActive = enum;
                guidata(hObject, handles);
            end

            function sliderChanged(src)
                handles = guidata(hObject);
                val = src.Value;
                feature_editfield_min.Value = val(1);
                feature_editfield_max.Value = val(2);
                handles.featureCurrentThresh(enum, 1) = val(1);
                handles.featureCurrentThresh(enum, 2) = val(2);

                handles.featureIsThresh = getThresholdCells(app, handles.featureData(enum, :), val);
                guidata(hObject, handles);
                updateThresh()
            end

            function editfieldChanged(src)
                handles = guidata(hObject);
                feature_slider.Value = [feature_editfield_min.Value  feature_editfield_max.Value];
                handles.featureCurrentThresh(enum, 1) = feature_editfield_min.Value;
                handles.featureCurrentThresh(enum, 2) = feature_editfield_max.Value;

                handles.featureIsThresh = getThresholdCells(app, handles.featureData(enum, :), [feature_editfield_min.Value  feature_editfield_max.Value]);
                guidata(hObject, handles);
                updateThresh();
            end

            function colorChanged(src)
                handles = guidata(hObject);
                handles.plusColorThresh = colorpicker_pos.Value;
                handles.negColorThresh = colorpicker_neg.Value;
                guidata(hObject, handles);
                updateThresh();
            end

            function updateThresh()
                %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
                handles.full_Tmask = createFullThreshMask(app,handles.full_raw,handles.full_bitmask,handles.Masks,handles.dMasks,handles.Screenx,handles.Screeny,handles.X_Max,handles.Y_Max, handles.featureIsThresh,handles.maskNum, handles.plusColorThresh, handles.negColorThresh, handles.header.Accession);

                if(ishandle(handles.MapFigHandle))
                    if(handles.isMask)
                        set(handles.MapFig, 'CData', handles.full_Tmask)
                    else
                        set(handles.MapFig, 'CData', handles.full_raw)
                    end
                end
                guidata(hObject, handles);
            end

            function figClose(feature_fig)
                handles = guidata(hObject);
                if(ishandle(handles.MapFigHandle))
                    if(handles.isMask)
                        set(handles.MapFig, 'CData', handles.full_mask)
                    else
                        set(handles.MapFig, 'CData', handles.full_raw)
                    end
                end

                writeThreshExcell(app, handles.opath, handles.oname, handles.featureMinMax, handles.featureCurrentThresh)

                delete(feature_fig);
                app.featurethreshold_pushbutton.Text =  sprintf('Threshold\nFeature');%['Threshold Feature'];
                guidata(hObject, handles);
                
            end

        end

        % Button pushed function: featureview_pushbutton
        function featureview_pushbuttonButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);            
            res = get(0,'screensize');
            resX = res(3);
            resY = res(4);
            clear res

            if(ishandle(handles.FeatureViewFigHandle))
                figClose(handles.FeatureViewFigHandle)
            else
            
                w = 300;
                h = resY-90;
                wb = 10;
                hb = 20;
    
                handles.FeatureViewFigHandle = uifigure('Name','Feature View','Position', [resX-w 60 w h]);
                handles.FeatureViewFigHandle.CloseRequestFcn = @(src,event)figClose(src);
    
                feature_table = uitable( handles.FeatureViewFigHandle);
                feature_table.Tag = "feature_table";
                feature_table.Position = [wb hb (w-2*wb) (h-2*hb)];
                feature_table.ColumnName = ["Feature","Value"];
                feature_table.Data = handles.featureLabels(1)';
                feature_table.Data(numel(handles.featureLabels),1) = handles.featureLabels(1)';
                feature_table.Data(:,1) = handles.featureLabels';
                feature_table.Data(:,2) = num2cell(handles.featureData(:, 1));
                feature_table.SelectionType = "row";
                %feature_table.RowName = ;
                feature_table.Multiselect = "on";
    

                app.featureview_pushbutton.Text = 'CLOSE';
                guidata(hObject, handles);
            end

            function figClose(feature_fig)
                delete(feature_fig);
                app.featureview_pushbutton.Text =  sprintf('Feature View');
                guidata(hObject, handles);
            end
        end

        % Button pushed function: training_pushbutton
        function training_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            
                res = get(0,'screensize');
                resX = res(3);
                resY = res(4);
                minres = min(resX, resY);
                clear res
                isDone = false;
            
                %get out string and format
                filenamesplit = get(handles.oname_edit, 'String');
                filenamesplit = strsplit(filenamesplit, '.');
                fileformat = filenamesplit{end};
                filename = '';
                for n=1:(numel(filenamesplit)-1)
                    filename = [filename, filenamesplit{n}];
                end

                filenamesplit = strsplit(filename, '_');
                if(isnan( str2double(filenamesplit{end}) )  )
                    %no number
                    filename = [filename, '_1'];
                    set(handles.oname_edit, 'String', [filename, '.', fileformat]);
                else
                    nextnum = str2double(filenamesplit{end}) + 1;
                    nextstr = '';
                    for n=1:(numel(filenamesplit)-1)
                        nextstr = [nextstr, filenamesplit{n}, '_' ];
                    end
                    filename = [nextstr, num2str(nextnum)];
                    set(handles.oname_edit, 'String', [filename, '.', fileformat]);
                end


                if(~ishandle(handles.MapFigHandle) && (handles.SelectionX~=-1))
            
                    handles.MapFigHandle = figure('Name', 'CMG Map');
                    set(gca,'units','pixels'); % set the axes units to pixels
                    x = get(gca,'position'); % get the position of the axes
                    set(gcf,'units','pixels'); % set the figure units to pixels
                    y = get(gcf,'position'); % get the figure position
                    set(gcf,'position',[y(1) y(2) x(3) x(4)])% set the position of the figure to the length and width of the axes
                    set(gca,'units','normalized','position',[0 0 1 1]) % set the axes units to pixels
                    set(gcf, 'OuterPosition', [(resX/2-minres/2) (resY/2-minres/2) minres minres])
                    %set(gcf, 'CloseRequestFcn', @(hObject,eventdata)CMG_Editor('Map_CloseRequestFcn',hObject,eventdata,handles))
                    h = zoom(handles.MapFigHandle);
                    h.ActionPostCallback = @zoomMapAspect;
                    set(gcf, 'ResizeFcn', @resize_fcn);
            
                    handles.isMap = true;
            
                    %handles.currentNumber = str2double(get(handles.nuclei_edit,'String'));
                    isMask = get(handles.showmask_checkbox,'Value');
            
            
                    %[handles.full_bitmask, handles.full_center, handles.full_mask] = createFullMask(hObject, eventdata, handles, handles.full_raw);
                    handles.MapFig = imshow(handles.full_mask);
                    [Y, X, ~] = size(handles.full_mask);
            
                    while(~isDone)
            
                        if(~handles.firstview)
                %             handles.MapFigHandle.CurrentAxes.XLim = handles.XLim;
                %             handles.MapFigHandle.CurrentAxes.YLim = handles.YLim;
                            set(gca, 'XLim', handles.XLim);
                            set(gca, 'YLim', handles.YLim);
                            rect = imrect(gca, handles.position);
                            setResizable(rect,false);
                        else
                            if(handles.SelectionX == 0)
                                rect = imrect(gca);
                                setResizable(rect,true);
                            else
                                rect = imrect(gca, [((X/2) - (handles.SelectionX/2)) ((Y/2) - (handles.SelectionY/2)) handles.SelectionX handles.SelectionY]);
                                setResizable(rect,false);
                            end
                        end


                        Sposition = wait(rect);
            
                        if(size(Sposition,1)==0)
                            isDone = true;
                        else


                            position = floor(Sposition);
            
                            position(3) = position(3)-1;
                            position(4) = position(4)-1;
            
                            for j=1:size(handles.full_data, 3)
                                frame_selected(:,:,j) = imcrop(handles.full_data(:,:,j), position);
                            end
            
                            [L] = findCells(app, hObject, eventdata, handles, position);
            
            
                            for i=1:numel(L)
                                xp = handles.Screenx(L(i));
                                yp = handles.Screeny(L(i));
                                mask = zeros(size(handles.full_mask, 1), size(handles.full_mask, 2), handles.header.NbBitMap(L(i)), 'uint8');
                                mask(  (yp+1):(yp+size(handles.Masks{L(i)}, 1)), (xp+1):(xp+size(handles.Masks{L(i)}, 2)), : ) = handles.Masks{L(i)};
                                for j=1:handles.header.NbBitMap(L(i))
                                    mask_selected(:,:,j) = imcrop(mask(:,:,j), position);
                                end
                                %mask_selected = imcrop(mask, position);
                                O_Images{i} = frame_selected;
                                O_Masks{i} = mask_selected;
            
                            end
                            O_Header = makeNewHeader(app, handles, L, position, 1);
            
                            if(handles.isSaved)

                                saving = figure('Position',[(resX/2)-(200/2) (resY/2)-(80/2),200,80],'Color',[0.9204,0.9087,0.8424],'MenuBar','none');
                                uicontrol('Style','text','String','SAVING.....','Position',[20,40,100,25]);
                                movegui(saving,'center');

                                if(strcmp(fileformat, 'cmg'))
                                    writeCMG( O_Header, O_Images, O_Masks, handles.opath, filename )
                                elseif(strcmp(fileformat, 'ccg'))
                                    writeCCG( O_Header, 0, O_Images, O_Masks, handles.opath, filename )
                                end
                                %close(handles.MapFigHandle)
                                try
                                    C = strsplit(filename, '_');
                                    curnum = str2double(C{end});
                                    nextnum = curnum+1;
                                    nextstr = '';
                                    for n=1:(numel(C)-1)
                                        nextstr = [nextstr, C{n}, '_' ];
                                    end
                                    filename = [nextstr, num2str(nextnum)];
                                    set(handles.oname_edit, 'String', [filename, '.', fileformat]);
                                catch
            
                                end
                                delete(saving);
                            else
                                disp('WARNING: Selection CMG/CCG not saved! Set outpath.')
                                %            [full_selectedmask] = getSelectedMask(hObject, eventdata, handles, L);
                                %            set(handles.MapFig, 'CData', full_selectedmask)
                            end
                            [full_selectedmask] = getSelectedMask(app, hObject, eventdata, handles, L);
                            set(handles.MapFig, 'CData', full_selectedmask)
            
                            %        handles.XLim = handles.MapFigHandle.CurrentAxes.XLim;
                            %        handles.YLim = handles.MapFigHandle.CurrentAxes.YLim;
                            handles.XLim = get(gca, 'XLim');
                            handles.YLim = get(gca, 'YLim');
                            handles.position = Sposition;
            
                            handles.firstview = false;
                            %close(handles.MapFigHandle)
                            
                            clear O_Images O_Masks O_Header
                        end
                    end
                end
            
            
                guidata(hObject, handles);
            
                function resize_fcn(varargin)
                    checkRatio
                end
            
                function zoomMapAspect(varargin)
                    checkRatio
                end
            
                function checkRatio
                    w_pos = get(gcf, 'position');
                    ratioXY = w_pos(4)/w_pos(3);
                    ratioYX = w_pos(3)/w_pos(4);
                    xlim = get(gca, 'XLim');
                    ylim = get(gca, 'YLim');
                    xlen = xlim(2)-xlim(1);
                    ylen = ylim(2)-ylim(1);
                    if( xlen > ylen )
                        s_ylen = xlen*ratioXY;
                        diff = s_ylen - ylen;
                        set(gca, 'YLim', [ylim(1)-(diff/2)  ylim(2)+(diff/2)])
                    elseif( ylen > xlen )
                        s_xlen = ylen*ratioYX;
                        diff = s_xlen - xlen;
                        set(gca, 'XLim', [xlim(1)-(diff/2)  xlim(2)+(diff/2)])
                    end
                end
        end

        % Value changed function: showmask_checkbox
        function showmask_checkbox_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);

            handles.isMask = app.showmask_checkbox.Value;
            guidata(hObject, handles);
            %update_Preview(app, hObject, eventdata, handles);
            if( ishandle(handles.MapFigHandle) )
                update_Map(app, event)
            end
            
        end

        % Button pushed function: channel_pushbutton
        function channel_pushbutton_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);            
            res = get(0,'screensize');
            resX = res(3);
            resY = res(4);
            clear res

            if(ishandle(handles.ChannelFigHandle))
                figClose(handles.ChannelFigHandle)
            else
                [maxImages, ~] = size(handles.colormap);
                if( isfile([num2str(maxImages),'_Channel_Labels.xlsx']) )
                    imagelabels = readcell([num2str(maxImages),'_Channel_Labels.xlsx'], 'Sheet', 1);
                else
                    for k=1:maxImages
                        imagelabels{k} = ['Channel: ', num2str(k)];
                    end
                end
                
                handles.ChannelFigHandle = uifigure('Name','Color Select.');
                handles.ChannelFigHandle.CloseRequestFcn = @(src,event)figClose(src);
                w = 400;
                h = 20 + 40*maxImages;
                
                set(handles.ChannelFigHandle, 'Position', [((resX/2)-(w/2)) ((resY/2)-(h/2)) w max(h, 320)])
                
                for k=1:maxImages
                    imagelabel(k) = uicontrol(handles.ChannelFigHandle,'Style','text','Units','pixels','HorizontalAlignment','left','Position',[25 (h-30)+(k-1)*-35 75 15],'String',imagelabels(k));
                    colorpickers(k) = uicolorpicker(handles.ChannelFigHandle,'Position',[100 (h-30)+(k-1)*-35 50 20],'Value',handles.colormap(k,1:3));
                    sliders(k) = uislider(handles.ChannelFigHandle,'Position',[160 (h-20)+(k-1)*-35 100 3],'Value',double(handles.colormap(k,4)));
                    sliders(k).Limits = [0, 1];
                    sliders(k).MajorTicks = [];
                    sliders(k).MinorTicks = [];
                    checkboxs(k) = uicheckbox(handles.ChannelFigHandle,'Position',[280 (h-30)+(k-1)*-35 50 20],'Value',handles.colormap(k,5), 'Text', '');
                end
                
                OKbutton = uicontrol(handles.ChannelFigHandle,'Style','pushbutton','Units','pixels','HorizontalAlignment','center','Position',[w-70 5 60 30],'String','Apply','Callback', {@(hObject,eventdata)RGBmap('OKbutton_Callback',hObject,eventdata,guidata(hObject))});
                set(OKbutton, Callback={@(src,event)figOK(src)})
                app.channel_pushbutton.Text = 'CLOSE';
                guidata(hObject, handles);
            end


            function figOK(channel_fig)
                handles = guidata(hObject);
                pos = get(app.ccg_editor_figure, 'Position');
                loading = figure('Position',[pos(1)+200 pos(2)+110,200,80],'Color',[0.9204,0.9087,0.8424],'MenuBar','none');
                uicontrol('Style','text','String','WORKING.....','Position',[20,40,100,25]);
                movegui(loading,'center');

                for k=1:maxImages
                    handles.colormap(k,1:3) = get(colorpickers(k),'Value');
                    handles.colormap(k,4) = get(sliders(k),'Value');
                    handles.colormap(k,5) = get(checkboxs(k),'Value');
                    colormap = handles.colormap;
                    matsave = ['ccgeditor_channelcolors_',num2str(size(colormap,1)),'.mat'];
                    save(matsave, 'colormap')
                end                

                [handles.full_raw] = createFullImage(app, handles.Images, handles.full_data, handles.full_logical, handles.colormap, handles.X_Max,handles.Y_Max,handles.MaxImages);
                [handles.full_mask] = makeRGBmap(app, handles.full_raw, handles.full_bitmask, handles.GroupColor, handles.maskColor);
                
                if(ishandle(handles.FeatureFigHandle))
                    handles.full_Tmask = createFullThreshMask(app,handles.full_raw,handles.full_bitmask,handles.Masks,handles.dMasks,handles.Screenx,handles.Screeny,handles.X_Max,handles.Y_Max, handles.featureIsThresh,handles.maskNum, handles.plusColorThresh, handles.negColorThresh, handles.header.Accession);
                end

                if(ishandle(handles.MapFigHandle))
                    if(handles.isMask)
                        if(ishandle(handles.FeatureFigHandle))
                            set(handles.MapFig, 'CData', handles.full_Tmask)
                        else
                            set(handles.MapFig, 'CData', handles.full_mask)
                        end
                    else
                        set(handles.MapFig, 'CData', handles.full_raw)
                    end
                end
                delete(loading);
                %figClose(handles.ChannelFigHandle)
                guidata(hObject, handles);
            end


            function figClose(channel_fig)
                delete(channel_fig);
                app.channel_pushbutton.Text =  sprintf('Channels');
                guidata(hObject, handles);
            end
        end

        % Button pushed function: groups_pushbutton
        function groups_pushbuttonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            res = get(0,'screensize');
            resX = res(3);
            resY = res(4);
            clear res

            if(ishandle(handles.GroupFigHandle))
                figClose(handles.GroupFigHandle)
            else

                numgroups = 256;
                numrows = 32;
                numcol = 2;
                %set(handles.editgroup_edit, 'Enable', 'off')
                isEdit = 0;
    
                for k=1:numgroups
                    imagelabels{k} = ['Group: ', num2str(k-1)];
                end
    
                handles.GroupFigHandle = uifigure('Name','Color Select.');
                handles.GroupFigHandle.CloseRequestFcn = @(src,event)figClose(src);
                w = 200*numcol;
                h = 40*numrows;
    
                set(handles.GroupFigHandle, 'Position', [((resX/2)-(w/2)) ((resY/2)-(h/2)) w max(h, 320)])
    
                for k=1:numrows
                    imagelabel1(k) = uicontrol(handles.GroupFigHandle,'Style','text','Units','pixels','HorizontalAlignment','left','Position',[25 (h-30)+(k-1)*-35 75 15],'String',imagelabels(k));
                    imagelabel2(k) = uicontrol(handles.GroupFigHandle,'Style','text','Units','pixels','HorizontalAlignment','left','Position',[225 (h-30)+(k-1)*-35 75 15],'String',imagelabels(k+32));

                    colorpickers1(k) = uicolorpicker(handles.GroupFigHandle,'Position',[100 (h-30)+(k-1)*-35 50 20],'Value',handles.GroupColor(k,1:3));
                    colorpickers2(k) = uicolorpicker(handles.GroupFigHandle,'Position',[300 (h-30)+(k-1)*-35 50 20],'Value',handles.GroupColor(k+32,1:3));

                    checkboxs1(k) = uicheckbox(handles.GroupFigHandle,'Position',[160 (h-30)+(k-1)*-35 50 20],'Value',handles.GroupColor(k,4), 'Text', '');
                    checkboxs2(k) = uicheckbox(handles.GroupFigHandle,'Position',[360 (h-30)+(k-1)*-35 50 20],'Value',handles.GroupColor(k+32,4), 'Text', '');
                end
    
                OKbutton = uicontrol(handles.GroupFigHandle,'Style','pushbutton','Units','pixels','HorizontalAlignment','center','Position',[w-70 5 60 30],'String','Apply','Callback', {@(hObject,eventdata)RGBmap('OKbutton_Callback',hObject,eventdata,guidata(hObject))});
                set(OKbutton, Callback={@(src,event)figOK(src)})
                app.groups_pushbutton.Text = 'CLOSE';
                guidata(hObject, handles);
            end


            function figOK(group_fig)
                handles = guidata(hObject);
                pos = get(app.ccg_editor_figure, 'Position');
                loading = figure('Position',[pos(1)+200 pos(2)+110,200,80],'Color',[0.9204,0.9087,0.8424],'MenuBar','none');
                uicontrol('Style','text','String','WORKING.....','Position',[20,40,100,25]);
                movegui(loading,'center');

                for k=1:numrows
                    handles.GroupColor(k,1:3) = get(colorpickers1(k),'Value');
                    handles.GroupColor(k+32,1:3) = get(colorpickers2(k),'Value');

                    handles.GroupColor(k,4) = get(checkboxs1(k),'Value');
                    handles.GroupColor(k+32,4) = get(checkboxs2(k),'Value');
                end
                activenum = str2double( get(handles.editgroup_edit, 'String') );


                set(handles.group_coloredit, 'Value', handles.GroupColor((activenum+1), 1:3))
                groupcolor = handles.GroupColor;
                save('ccgeditor_groupcolors.mat', 'groupcolor')

                %[handles.full_raw] = createFullImage(app, handles.full_data, handles.full_logical, handles.colormap, handles.X_Min,handles.Y_Min,handles.X_Max,handles.Y_Max,handles.MaxImages);
                %[handles.full_mask] = makeRGBmap(app, handles.full_raw, handles.full_bitmask, handles.GroupColor );
                [handles.full_mask, handles.full_bitmask, ~] = createFullMask(app,handles.full_raw,handles.GroupColor, handles.maskColor, handles.Masks,handles.dMasks,handles.N,handles.Screenx,handles.Screeny,handles.vorx,handles.vory,handles.header.Group,handles.X_Max,handles.Y_Max,handles.maskNum);
                
                if(ishandle(handles.MapFigHandle))
                    if(handles.isMask)
                        set(handles.MapFig, 'CData', handles.full_mask)
                    else
                        set(handles.MapFig, 'CData', handles.full_raw)
                    end
                end
                delete(loading);
                guidata(hObject, handles);
                %figClose(handles.GroupFigHandle)

            end

            function figClose(group_fig)
                delete(group_fig);
                app.groups_pushbutton.Text =  sprintf('Groups');
                guidata(hObject, handles);
            end

        end

        % Button pushed function: edit_map_pushbutton
        function edit_map_pushbutton_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            res = get(0,'screensize');
            resX = res(3);
            resY = res(4);
            minres = min(resX, resY);
            clear res

            if( ishandle(handles.MapFigHandle) )
                figClose(handles.MapFigHandle)
            else
    
                SE = strel('square',3);
                color = [
                    0.3010 0.7450 0.9330,
                    0.6350 0.0780 0.1840,
                    0.0000 0.4470 0.7410,
                    0.8500 0.3250 0.0980,
                    0.9290 0.6940 0.1250,
                    0.4940 0.1840 0.5560,
                    0.4660 0.6740 0.1880
                    ];


                handles.MapFigHandle = figure('Name', 'CMG Map');
                handles.MapFigHandle.CloseRequestFcn = @(src,event)figClose(src);

                set(gcf,'units','pixels');
                addToolbarExplorationButtons(gcf)
                set(gcf, 'OuterPosition', [(resX/2-minres/2)+1 (resY/2-minres/2)+1 minres-1 minres-1])
                set(gca,'units','pixels');
                set(gca,'units','normalized','position',[0 0 1 1])
                x = get(gca,'position'); % get the position of the axes
                addToolbarExplorationButtons(gcf)
                %set(handles.MapFigHandle, 'CloseRequestFcn', {@Map_CloseRequestFcn} )

                h = zoom(handles.MapFigHandle);
                h.ActionPostCallback = @zoomMapAspect;
                set(gcf, 'ResizeFcn', @resize_fcn);

                % handles.MapFigViewer = viewer2d(handles.MapFigHandle);
                % handles.MapFigViewer.ScaleBar = "on";
                
                %zoomMapAspect
                %handles.MapFigHandle.ActionPostCallback = {@zoomMapAspect,handles};


                app.edit_map_pushbutton.Text = 'CLOSE';


                %isMask = get(handles.showmask_checkbox,'Value');


                %[handles.full_bitmask, handles.full_center, handles.full_mask] = createFullMask(hObject, eventdata, handles, handles.full_raw);

                if(handles.isMask)
                    handles.MapFig = imshow(handles.full_mask);
                    %handles.MapFig = imageshow(handles.full_mask,Parent=handles.MapFigViewer);
                    %handles.MapFig = image(handles.full_mask);
                else
                    handles.MapFig = imshow(handles.full_raw);
                    %handles.MapFig = imageshow(handles.full_raw,Parent=handles.MapFigViewer);
                    %handles.MapFig = image(handles.full_raw);
                end


                currentdir = cd;

                cd(handles.cmgpath)
                roi_ = dir('*.roi');
                cd(currentdir)

                K = numel(roi_);

                for k=1:K
                    [ basal_roi, external_roi, nuclei_centers, header, P, PP ] = readROI( handles.cmgpath, roi_(k).name );
                    if( P(1,1)~=0 || P(1,2)~=0 )
                        handles.ROI_list(k) = images.roi.Polyline(gca,'Position',P,'Color',color( mod(k,7)+1, : ), 'InteractionsAllowed','none');
                    end
                end

                guidata(hObject, handles);

                isAbort=0;
                while(~isAbort)
                    [handles, SelectedCell, sMode, isAbort] = selectCell(app, event);
                    switch sMode

                        case 0%Add Cell(s)

                            [Y, X, Z] = size(handles.full_raw);
                            [bitmap, nuclei_mask, ~, isAbort, useMask] = getMultiFreeHand_snap2pixels(Y, X, handles.MapFig, handles.MapFigHandle);
                            masks = bitMap2MaskArray(bitmap);
                            [~,~,Z] = size(masks);

                            pos = get(app.ccg_editor_figure, 'Position');
                            loading = figure('Position',[pos(1)+200 pos(2)+110,200,80],'Color',[0.9204,0.9087,0.8424],'MenuBar','none');
                            uicontrol('Style','text','String','WORKING.....','Position',[20,40,100,25]);
                            movegui(loading,'center');

                            for z=1:Z

                                if(   sum(sum(masks(:,:,z))) > 0   )


                                    [Image, Mask, ScreenX, ScreenY, vorx, vory] = newNuclei(app, handles, masks(:,:,z));

                                    %Image = handles.full_raw( (centroid(2)-(Ysel2-1)):(centroid(2)+Ysel2) , (centroid(1)-(Xsel2-1)):(centroid(1)+Xsel2) );
                                    %Mask = mask( (centroid(2)-(Ysel2-1)):(centroid(2)+Ysel2) , (centroid(1)-(Xsel2-1)):(centroid(1)+Xsel2) );


                                    N = numel(handles.Images) + 1;
                                    handles.Images{N} = Image;
                                    handles.Masks{N} = Mask;
                                    handles.dMasks{N} = imdilate(Mask, SE) - Mask;

                                    handles.header.Mode(N) = handles.header.Mode(1);
                                    handles.header.NbColorMap(N) = handles.header.NbColorMap(1);
                                    handles.header.Class(N) = handles.header.Class(1);

                                    handles.header.Screenx(N) = uint32(ScreenX);
                                    handles.header.Screeny(N) = uint32(ScreenY);

                                    handles.header.Stagex(N) = handles.header.Stagex(1);
                                    handles.header.Stagey(N) = handles.header.Stagey(1);
                                    handles.header.Stagez(N) = handles.header.Stagez(1);
                                    handles.header.Resolution(N) = handles.header.Resolution(1);
                                    handles.header.LowThreshold(N) = uint16(9728);%handles.header.LowThreshold(1);
                                    handles.header.MidThreshold(N) = uint16(4864);%handles.header.MidThreshold(1);
                                    handles.header.Group(N) = uint8( str2double( app.editgroup_edit.Value ) );
                                    handles.header.Width(N) = uint32(128);
                                    handles.header.Height(N) = uint32(128);
                                    handles.header.Accession(N) = uint32(0);
                                    handles.header.Iod(N) = single(22500);%handles.header.Iod(1);
                                    handles.header.Fluor(N) = handles.header.Fluor(1);
                                    handles.header.Diagnosis(N) = handles.header.Diagnosis(1);
                                    handles.header.RedFaction(N) = handles.header.RedFaction(1);
                                    handles.header.GreenFaction(N) = handles.header.GreenFaction(1);
                                    handles.header.BlueFaction(N) = handles.header.BlueFaction(1);
                                    handles.header.Index(N) = handles.header.Index(1);
                                    handles.header.Objective(N) = handles.header.Objective(1);
                                    handles.header.Calibrated(N) = handles.header.Calibrated(1);
                                    handles.header.StackX_int(N) = handles.header.StackX_int(1);
                                    handles.header.StackY_int(N) = handles.header.StackY_int(1);
                                    handles.header.NbBitMap(N) = uint8(1);
                                    handles.header.CassettePosition(N) = handles.header.CassettePosition(1);

                                    handles.header.vorx(N) = uint32(vorx);
                                    handles.header.vory(N) = uint32(vory);

                                    handles.header.BestFocusFrame(N) = handles.header.BestFocusFrame(1);
                                    handles.header.BackgroundFloat(N) = handles.header.BackgroundFloat(1);
                                    handles.header.PrimaryColourChannel(N) = handles.header.PrimaryColourChannel(1);
                                    handles.header.Layer(N) = uint16(0);
                                    handles.header.Points{N} = uint8([0 0 0 0 0 0 0 0 0]);
                                    handles.header.NumFeatures(N) = handles.header.NumFeatures(1);
                                    handles.header.RGB_Order{N} = uint8([0 1 2]);

                                    restoreAccession(app, hObject, eventdata, handles)

                                    handles.Screenx(N) = uint32(ScreenX);
                                    handles.Screeny(N) = uint32(ScreenY);
                                    handles.vorx(N) = uint32(vorx);
                                    handles.vory(N) = uint32(vory);

                                    handles.saveScreenVor(N, 1) = uint32(ScreenX);
                                    handles.saveScreenVor(N, 2) = uint32(ScreenY);  
                                    handles.saveScreenVor(N, 3) = uint32(vorx);
                                    handles.saveScreenVor(N, 4) = uint32(vory);  

                                    handles.N = handles.N + 1;
                                    handles.isSaved = false;
                                end

                            end
                            [handles.full_mask, ~, ~] = createFullMask(app,handles.full_raw,handles.GroupColor,handles.maskColor,handles.Masks,handles.dMasks,handles.N,handles.Screenx,handles.Screeny,handles.vorx,handles.vory,handles.header.Group,handles.X_Max,handles.Y_Max,handles.maskNum);
                            %[~,~,handles.full_mask] = createFullMask(app, event);
                            guidata(hObject, handles);
                            update_Map(app, event);
                            guidata(hObject, handles);
                            delete(loading);


                        case 1%Edit Cell Boundry

                            if(SelectedCell > 0 && ~isAbort)
                                pos = get(app.ccg_editor_figure, 'Position');
                                loading = figure('Position',[pos(1)+200 pos(2)+110,200,80],'Color',[0.9204,0.9087,0.8424],'MenuBar','none');
                                uicontrol('Style','text','String','WORKING.....','Position',[20,40,100,25]);
                                movegui(loading,'center');

                                handles.currentNumber = SelectedCell;

                                handles.EditorFigHandle = figure('Name', 'Editor');

                                set(gcf,'units','pixels');
                                set(gcf, 'OuterPosition', [(resX/2-minres/2)+1 (resY/2-minres/2)+1 minres-1 minres-1])
                                set(gca,'units','pixels');
                                set(gca,'units','normalized','position',[0 0 1 1])
                                addToolbarExplorationButtons(gcf)

                                Sx = handles.Screenx(SelectedCell);
                                Sy = handles.Screeny(SelectedCell);
                                W = handles.header.Width(SelectedCell);
                                H = handles.header.Height(SelectedCell);

                                handles.EditorFig = imshow( handles.full_raw( (Sy+1):(Sy+H) , (Sx+1):(Sx+W), : ) );
                                [bitmap, nuclei_mask, ~, isAbortedit, useMask] = getMultiFreeHand_snap2pixels(double(H), double(W), handles.EditorFig, handles.EditorFigHandle);
                                masks = bitMap2MaskArray(bitmap);

                                try
                                    if(ishandle(handles.EditorFigHandle))
                                        close(handles.EditorFigHandle)
                                    else
                                        isAbortedit=1;
                                    end
                                catch

                                end


                                if(~isAbortedit)

                                    handles.Masks{SelectedCell}(:,:,handles.maskNum) = masks;
                                    handles.dMasks{SelectedCell}(:,:,handles.maskNum) = imdilate(masks, SE) - masks;
                                    handles.isSaved = false;
                                    [handles.full_mask, ~, ~] = createFullMask(app,handles.full_raw,handles.GroupColor,handles.maskColor,handles.Masks,handles.dMasks,handles.N,handles.Screenx,handles.Screeny,handles.vorx,handles.vory,handles.header.Group,handles.X_Max,handles.Y_Max,handles.maskNum);
                                    %[~,~,handles.full_mask] = createFullMask(app, event);
                                    guidata(hObject, handles);
                                    update_Map(app, event);
                                end
                                guidata(hObject, handles);
                                delete(loading);
                            end


                        case 2%Edit Cell Group

                            pos = get(app.ccg_editor_figure, 'Position');
                            loading = figure('Position',[pos(1)+200 pos(2)+110,200,80],'Color',[0.9204,0.9087,0.8424],'MenuBar','none');
                            uicontrol('Style','text','String','WORKING.....','Position',[20,40,100,25]);
                            movegui(loading,'center');

                            handles.currentNumber = SelectedCell;
                            groupnum = str2double(get(app.editgroup_edit, 'Value'));
                            handles.header.Group(SelectedCell) = uint8(groupnum);
                            handles.isSaved = false;
                            [handles.full_mask, ~, ~] = createFullMask(app,handles.full_raw,handles.GroupColor,handles.maskColor,handles.Masks,handles.dMasks,handles.N,handles.Screenx,handles.Screeny,handles.vorx,handles.vory,handles.header.Group,handles.X_Max,handles.Y_Max,handles.maskNum);

                            guidata(hObject, handles);
                            update_Map(app, event);
                            guidata(hObject, handles);
                            delete(loading);

                        case 3%Add Mask

                            if(SelectedCell > 0 && ~isAbort)
                                pos = get(app.ccg_editor_figure, 'Position');
                                loading = figure('Position',[pos(1)+200 pos(2)+110,200,80],'Color',[0.9204,0.9087,0.8424],'MenuBar','none');
                                uicontrol('Style','text','String','WORKING.....','Position',[20,40,100,25]);
                                movegui(loading,'center');


                                handles.currentNumber = SelectedCell;

                                handles.EditorFigHandle = figure('Name', 'Editor');

                                set(gcf,'units','pixels');
                                set(gcf, 'OuterPosition', [(resX/2-minres/2) (resY/2-minres/2) minres minres])
                                set(gca,'units','pixels');
                                set(gca,'units','normalized','position',[0 0 1 1])

                                Sx = handles.Screenx(SelectedCell);
                                Sy = handles.Screeny(SelectedCell);

                                W = handles.header.Width(SelectedCell);
                                H = handles.header.Height(SelectedCell);

                                handles.EditorFig = imshow( handles.full_raw( (Sy+1):(Sy+H) , (Sx+1):(Sx+W), : ) );
                                [bitmap, nuclei_mask, ~, isAbort, useMask] = getMultiFreeHand_snap2pixels(double(H), double(W), handles.EditorFig, handles.EditorFigHandle);
                                masks = bitMap2MaskArray(bitmap);

                                close(handles.EditorFigHandle)

                                [~,~,Z] = size(handles.Masks{SelectedCell});
                                handles.Masks{SelectedCell}(:,:,Z+1) = masks;
                                handles.dMasks{SelectedCell}(:,:,Z+1) = imdilate(masks, SE) - masks;
                                handles.header.NbBitMap(SelectedCell) = handles.header.NbBitMap(SelectedCell) + 1;
                                handles.isSaved = false;
                                if( max(handles.header.NbBitMap) > handles.MaxMasks )
                                    handles.MaxMasks = handles.MaxMasks + 1;
                                    handles.maskNum(handles.MaxMasks) = 0;
                                end

                                [handles.full_mask, ~, ~] = createFullMask(app,handles.full_raw,handles.GroupColor,handles.maskColor,handles.Masks,handles.dMasks,handles.N,handles.Screenx,handles.Screeny,handles.vorx,handles.vory,handles.header.Group,handles.X_Max,handles.Y_Max,handles.maskNum);
                                
                                guidata(hObject, handles);
                                update_Map(app, event);
                                delete(loading);
                            end

                        case 4%Remove Mask

                            if(SelectedCell > 0 && ~isAbort)
                                pos = get(app.ccg_editor_figure, 'Position');
                                loading = figure('Position',[pos(1)+200 pos(2)+110,200,80],'Color',[0.9204,0.9087,0.8424],'MenuBar','none');
                                uicontrol('Style','text','String','WORKING.....','Position',[20,40,100,25]);
                                movegui(loading,'center');

                                handles.currentNumber = SelectedCell;
                                maskNum = handles.maskNum;
                                [Y, X, Z] = size(handles.Masks{SelectedCell});
                                M = numel(maskNum);
                                for m=1:M
                                    mm = M - (m-1);
                                    if(maskNum(mm))
                                        % handles.Masks{SelectedCell}(:,:,mm) = zeros(Y, X, 'uint8');
                                        % handles.dMasks{SelectedCell}(:,:,mm) = zeros(Y, X, 'uint8');
                                        handles.Masks{SelectedCell}(:,:,mm) = [];
                                        handles.dMasks{SelectedCell}(:,:,mm) = [];
                                        % if( strcmp(handles.filetype, 'ccg') )
                                        %     handles.MaskLabel{SelectedCell}(mm) = [];
                                        % end
                                    end
                                end

                                [Y, X, Z] = size(handles.Masks{SelectedCell});
                                handles.header.NbBitMap(SelectedCell) = Z;
                                handles.isSaved = false;
                                [handles.full_mask, ~, ~] = createFullMask(app,handles.full_raw,handles.GroupColor,handles.maskColor,handles.Masks,handles.dMasks,handles.N,handles.Screenx,handles.Screeny,handles.vorx,handles.vory,handles.header.Group,handles.X_Max,handles.Y_Max,handles.maskNum);
                                %[~,~,handles.full_mask] = createFullMask(app, event);

                                guidata(hObject, handles);
                                update_Map(app, event);
                                delete(loading);

                            end
                        case 5%Delete Cell

                            if(SelectedCell > 0 && ~isAbort)

                                pos = get(app.ccg_editor_figure, 'Position');
                                loading = figure('Position',[pos(1)+200 pos(2)+110,200,80],'Color',[0.9204,0.9087,0.8424],'MenuBar','none');
                                uicontrol('Style','text','String','WORKING.....','Position',[20,40,100,25]);
                                movegui(loading,'center');


                                handles.currentNumber = SelectedCell;
                                %disp('Deleting...')
                                m = SelectedCell;
                                handles.Images(m) = [];
                                handles.Masks(m) = [];
                                handles.dMasks(m) = [];

                                handles.header.Mode(m) = [];
                                handles.header.NbColorMap(m) = [];
                                handles.header.Class(m) = [];
                                handles.header.Screenx(m) = [];
                                handles.header.Screeny(m) = [];
                                handles.header.Stagex(m) = [];
                                handles.header.Stagey(m) = [];
                                handles.header.Stagez(m) = [];
                                handles.header.Resolution(m) = [];
                                handles.header.LowThreshold(m) = [];
                                handles.header.MidThreshold(m) = [];
                                handles.header.Group(m) = [];
                                handles.header.Width(m) = [];
                                handles.header.Height(m) = [];
                                handles.header.Accession(m) = [];
                                handles.header.Iod(m) = [];
                                handles.header.Fluor(m) = [];
                                handles.header.Diagnosis(m) = [];
                                handles.header.RedFaction(m) = [];
                                handles.header.GreenFaction(m) = [];
                                handles.header.BlueFaction(m) = [];
                                handles.header.Index(m) = [];
                                handles.header.Objective(m) = [];
                                handles.header.Calibrated(m) = [];
                                handles.header.StackX_int(m) = [];
                                handles.header.StackY_int(m) = [];
                                handles.header.NbBitMap(m) = [];
                                handles.header.CassettePosition(m) = [];
                                handles.header.vorx(m) = [];
                                handles.header.vory(m) = [];
                                handles.header.BestFocusFrame(m) = [];
                                handles.header.BackgroundFloat(m) = [];
                                handles.header.PrimaryColourChannel(m) = [];
                                handles.header.Layer(m) = [];
                                handles.header.Points(m) = [];
                                handles.header.NumFeatures(m) = [];
                                handles.header.RGB_Order(m) = [];

                                restoreAccession(app, hObject, eventdata, handles)

                                handles.Screenx(m) = [];
                                handles.Screeny(m) = [];
                                handles.vorx(m) = [];
                                handles.vory(m) = [];
                                handles.saveScreenVor(m, :) = [];

                                handles.N = handles.N - 1;
                                handles.isSaved = false;
                                %disp('Deleted.')
                                %else
                                %   disp('Selected was not deleted.')
                                %end

                                [handles.full_mask, ~, ~] = createFullMask(app,handles.full_raw,handles.GroupColor,handles.maskColor,handles.Masks,handles.dMasks,handles.N,handles.Screenx,handles.Screeny,handles.vorx,handles.vory,handles.header.Group,handles.X_Max,handles.Y_Max,handles.maskNum);
                                %[~,~,handles.full_mask] = createFullMask(app, event);
                                guidata(hObject, handles);
                                update_Map(app, event);
                                guidata(hObject, handles);
                                delete(loading);
                            end


                        otherwise

                    end


                end
                %roi = drawpoint;

                    %update_Preview(hObject, eventdata, handles)
                    %set(handles.edit_map_pushbutton, 'String', 'Close')

                try
                    guidata(hObject, handles);
                catch
                end

            end


            function resize_fcn(varargin)
                checkRatio

                %         set(gcf,'units','pixels');
                %         set(gca,'units','pixels');
                %         w_pos = get(gcf, 'position');
                %         set(gca, 'XLim', [0  w_pos(3)])
                %         set(gca, 'YLim', [0  w_pos(4)])
                %         set(gca, 'position', [0 0 w_pos(3) w_pos(4)]);
                %         disp(['X:',num2str(w_pos(3)),' Y:',num2str(w_pos(4))])
                %         set(gca, 'DataAspectRatio', [w_pos(3) w_pos(4) 1])

                %         fig_pos = get(gcf,'position'); % get the figure position
                %         set(gca, 'PlotBoxAspectRatioMode', 'manual')
                %         set(gca, 'PlotBoxAspectRatio', [fig_pos(3) fig_pos(4) 1])

            end

            function zoomMapAspect(varargin)
                checkRatio
                %         x = get(gcf,'position'); % get the position of the axes
                %         %set(gca, 'PlotBoxAspectRatio', [x(3) x(4) 1])
                %         pbaspect([x(3) x(4) 1]);

                %disp(['X:',num2str(w_pos(3)),' Y:',num2str(w_pos(4))])
                %set(gca, 'DataAspectRatio', [w_pos(3) w_pos(4) 1])
            end

            function checkRatio
                w_pos = get(gcf, 'position');
                ratioXY = w_pos(4)/w_pos(3);
                ratioYX = w_pos(3)/w_pos(4);
                xlim = get(gca, 'XLim');
                ylim = get(gca, 'YLim');
                xlen = xlim(2)-xlim(1);
                ylen = ylim(2)-ylim(1);
                if( xlen > ylen )
                    s_ylen = xlen*ratioXY;
                    diff = s_ylen - ylen;
                    set(gca, 'YLim', [ylim(1)-(diff/2)  ylim(2)+(diff/2)])
                elseif( ylen > xlen )
                    s_xlen = ylen*ratioYX;
                    diff = s_xlen - xlen;
                    set(gca, 'XLim', [xlim(1)-(diff/2)  xlim(2)+(diff/2)])
                end
            end

            function figClose(map_fig)
                delete(map_fig); 
                app.edit_map_pushbutton.Text =  sprintf('Edit View');
                guidata(hObject, handles);
            end
        end

        % Button pushed function: masks_pushbutton
        function masks_pushbuttonButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);            
            res = get(0,'screensize');
            resX = res(3);
            resY = res(4);
            clear res

            if(ishandle(handles.MaskFigHandle))
                figClose(handles.MaskFigHandle)
            else

                [maxMasks, ~] = size(handles.maskNum);
                for k=1:maxMasks
                    masklabels{k} = ['Mask: ', num2str(k)];
                end
    
                handles.MaskFigHandle = uifigure('Name','Mask Select.');
                handles.MaskFigHandle.CloseRequestFcn = @(src,event)figClose(src);
                w = 250;
                h = 340 + 35*maxMasks;
    
                set(handles.MaskFigHandle, 'Position', [((resX/2)-(w/2)) ((resY/2)-(h/2)) w h])
                
                use_mask_label = uilabel(handles.MaskFigHandle, 'Position',[15 h-30 50 20], 'Text', 'Show');
                color_mask_label = uilabel(handles.MaskFigHandle, 'Position',[175 h-30 50 20], 'Text', 'Color');
                save_mask_label = uilabel(handles.MaskFigHandle, 'Position',[215 h-30 50 20], 'Text', 'Save');
    
    
                for k=1:maxMasks
                    checkboxs(k) = uicheckbox(handles.MaskFigHandle,'Position',[20 (h-60)+(k-1)*-35 50 20],'Value',handles.maskNum(k), 'Text', '');
                    masklabel(k) = uicontrol(handles.MaskFigHandle,'Style','text','Units','pixels','HorizontalAlignment','left','Position',[45 (h-60)+(k-1)*-35 75 15],'String',masklabels(k));
                    colorpicker(k) = uicolorpicker(handles.MaskFigHandle,'Position',[100 (h-60)+(k-1)*-35 50 20],'Value',handles.maskColor(k,1:3));
                    color_checkboxs(k) = uicheckbox(handles.MaskFigHandle,'Position',[180 (h-60)+(k-1)*-35 50 20],'Value',handles.maskColor(k, 4), 'Text', '');
                    save_checkboxs(k) = uicheckbox(handles.MaskFigHandle,'Position',[220 (h-60)+(k-1)*-35 50 20],'Value',handles.maskColor(k, 5), 'Text', '');
                end
    
                OKbutton = uicontrol(handles.MaskFigHandle,'Style','pushbutton','Units','pixels','HorizontalAlignment','center','Position',[w-70 5 60 30],'String','Apply','Callback', {@(hObject,eventdata)RGBmap('OKbutton_Callback',hObject,eventdata,guidata(hObject))});
                set(OKbutton, Callback={@(src,event)figOK(src)})
                app.masks_pushbutton.Text = 'CLOSE';
                guidata(hObject, handles);


            end


            function figOK(mask_fig)
                handles = guidata(hObject);
                pos = get(app.ccg_editor_figure, 'Position');
                loading = figure('Position',[pos(1)+200 pos(2)+110,200,80],'Color',[0.9204,0.9087,0.8424],'MenuBar','none');
                uicontrol('Style','text','String','WORKING.....','Position',[20,40,100,25]);
                movegui(loading,'center');

                for k=1:maxMasks
                    handles.maskNum(k) = get(checkboxs(k),'Value');
                    handles.maskColor(k, 4) = get(color_checkboxs(k),'Value');
                    handles.maskColor(k, 1:3) = get(colorpicker(k),'Value');
                    handles.maskColor(k, 5) = get(save_checkboxs(k),'Value');
                end
                maskcolor = handles.maskColor;
                maskNum = handles.maskNum;
                save(['ccgeditor_maskcolors_',num2str(maxMasks),'.mat'], 'maskcolor', 'maskNum')

                %[handles.full_raw] = createFullImage(app, handles.Images, handles.full_data, handles.full_logical, handles.colormap, handles.X_Min,handles.Y_Min,handles.X_Max,handles.Y_Max,handles.MaxImages);
                %[handles.full_mask] = makeRGBmap(app, handles.full_raw, handles.full_bitmask, handles.GroupColor );
                [handles.full_mask, handles.full_bitmask, handles.full_center] = createFullMask(app,handles.full_raw,handles.GroupColor,handles.maskColor,handles.Masks,handles.dMasks,handles.N,handles.Screenx,handles.Screeny,handles.vorx,handles.vory,handles.header.Group,handles.X_Max,handles.Y_Max,handles.maskNum);

                if(ishandle(handles.FeatureFigHandle))
                    handles.full_Tmask = createFullThreshMask(app,handles.full_raw,handles.full_bitmask,handles.Masks,handles.dMasks,handles.Screenx,handles.Screeny,handles.X_Max,handles.Y_Max, handles.featureIsThresh,handles.maskNum, handles.plusColorThresh, handles.negColorThresh, handles.header.Accession);
                end

                if(ishandle(handles.MapFigHandle))
                    if(handles.isMask)
                        if(ishandle(handles.FeatureFigHandle))
                            set(handles.MapFig, 'CData', handles.full_Tmask)
                        else
                            set(handles.MapFig, 'CData', handles.full_mask)
                        end
                    else
                        set(handles.MapFig, 'CData', handles.full_raw)
                    end
                end
                delete(loading);
                guidata(hObject, handles);
                %figClose(handles.MaskFigHandle)
            end

            function figClose(mask_fig)
                delete(mask_fig);
                app.masks_pushbutton.Text =  sprintf('Masks');
                guidata(hObject, handles);
            end
        end

        % Value changed function: gallerymode_checkBox
        function gallerymode_checkBoxValueChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);

            value = app.gallerymode_checkBox.Value;
            if(value)
                handles.isGallery = 1;
            else
                handles.isGallery = 0;
            end
            
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ccg_editor_figure = uifigure('Visible', 'off');
            app.ccg_editor_figure.Name = 'CCG_Editor';
            app.ccg_editor_figure.CloseRequestFcn = createCallbackFcn(app, @ccg_editor_figure_CloseRequestFcn, true);
            app.ccg_editor_figure.Tag = 'ccg_editor_figure';
            app.source_pushbutton = uibutton(app.ccg_editor_figure, 'push');
            app.source_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @source_pushbutton_Callback, true);
            app.source_pushbutton.Tag = 'source_pushbutton';
            app.source_pushbutton.Text = 'Source';
            app.ipath_text = uilabel(app.ccg_editor_figure);
            app.ipath_text.Tag = 'ipath_text';
            app.ipath_text.Text = ':::   No CMG Selected  :::';
            app.showmask_checkbox = uicheckbox(app.ccg_editor_figure);
            app.showmask_checkbox.ValueChangedFcn = createCallbackFcn(app, @showmask_checkbox_Callback, true);
            app.showmask_checkbox.Tag = 'showmask_checkbox';
            app.showmask_checkbox.Tooltip = {''};
            app.showmask_checkbox.Enable = 'off';
            app.showmask_checkbox.Text = 'Masks';
            app.showmask_checkbox.Value = true;
            app.outdir_pushbutton = uibutton(app.ccg_editor_figure, 'push');
            app.outdir_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @outdir_pushbutton_Callback, true);
            app.outdir_pushbutton.Tag = 'outdir_pushbutton';
            app.outdir_pushbutton.Enable = 'off';
            app.outdir_pushbutton.Text = 'Output';
            app.oname_edit = uieditfield(app.ccg_editor_figure, 'text');
            app.oname_edit.ValueChangedFcn = createCallbackFcn(app, @oname_editValueChanged, true);
            app.oname_edit.Tag = 'oname_edit';
            app.oname_edit.Enable = 'off';
            app.edit_map_pushbutton = uibutton(app.ccg_editor_figure, 'push');
            app.edit_map_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @edit_map_pushbutton_Callback, true);
            app.edit_map_pushbutton.Tag = 'edit_map_pushbutton';
            app.edit_map_pushbutton.Enable = 'off';
            app.edit_map_pushbutton.Tooltip = {'(LeftClick)=Select'; '(Insert)=Add Cell'; '(Delete)=Delete Cell'; '(Home)=Update Group'; '(Enter)=Edit Mask'; '(Equals)=Add Mask'; '(Hyphen)=Remove Mask'};
            app.edit_map_pushbutton.Text = 'Edit View';
            app.save_pushbutton = uibutton(app.ccg_editor_figure, 'push');
            app.save_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @save_pushbutton_Callback, true);
            app.save_pushbutton.Tag = 'save_pushbutton';
            app.save_pushbutton.Enable = 'off';
            app.save_pushbutton.Text = 'Save CMG';
            app.training_pushbutton = uibutton(app.ccg_editor_figure, 'push');
            app.training_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @training_pushbutton_Callback, true);
            app.training_pushbutton.Tag = 'training_pushbutton';
            app.training_pushbutton.Enable = 'off';
            app.training_pushbutton.Text = 'Training...';
            app.size_uipanel = uibuttongroup(app.ccg_editor_figure);
            app.size_uipanel.SelectionChangedFcn = createCallbackFcn(app, @size_uipanel_SelectionChangeFcn, true);
            app.size_uipanel.Title = 'Size';
            app.size_uipanel.Tag = 'size_uipanel';
            app.s128_radiobutton = uiradiobutton(app.size_uipanel);
            app.s128_radiobutton.Tag = 's128_radiobutton';
            app.s128_radiobutton.Text = '128';
            app.s256_radiobutton = uiradiobutton(app.size_uipanel);
            app.s256_radiobutton.Tag = 's256_radiobutton';
            app.s256_radiobutton.Text = '256';
            app.s256_radiobutton.Value = true;
            app.sany_radiobutton = uiradiobutton(app.size_uipanel);
            app.sany_radiobutton.Tag = 'sany_radiobutton';
            app.sany_radiobutton.Text = 'Any';
            app.save_popupmenu = uidropdown(app.ccg_editor_figure);
            app.save_popupmenu.Items = {'All Nuclei', 'Seperate Nuclei', 'Save 256 Nuclei'};
            app.save_popupmenu.ValueChangedFcn = createCallbackFcn(app, @save_popupmenu_Callback, true);
            app.save_popupmenu.Tag = 'save_popupmenu';
            app.save_popupmenu.Value = 'All Nuclei';
            app.channel_pushbutton = uibutton(app.ccg_editor_figure, 'push');
            app.channel_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @channel_pushbutton_Callback, true);
            app.channel_pushbutton.Tag = 'channel_pushbutton';
            app.channel_pushbutton.Enable = 'off';
            app.channel_pushbutton.Text = 'Channels';
            app.roi_pushbutton = uibutton(app.ccg_editor_figure, 'push');
            app.roi_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @roi_pushbutton_Callback, true);
            app.roi_pushbutton.Tag = 'roi_pushbutton';
            app.roi_pushbutton.Enable = 'off';
            app.roi_pushbutton.Text = 'Create ROI';
            app.editgroup_edit = uieditfield(app.ccg_editor_figure, 'text');
            app.editgroup_edit.CharacterLimits = [0 255];
            app.editgroup_edit.InputType = 'digits';
            app.editgroup_edit.ValueChangedFcn = createCallbackFcn(app, @editgroup_edit_Callback, true);
            app.editgroup_edit.Tag = 'editgroup_edit';
            app.editgroup_edit.Enable = 'off';
            app.editgroup_edit.Value = '0';
            app.editgroup_pushbutton = uibutton(app.ccg_editor_figure, 'push');
            app.editgroup_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @editgroup_pushbutton_Callback, true);
            app.editgroup_pushbutton.Tag = 'editgroup_pushbutton';
            app.editgroup_pushbutton.Enable = 'off';
            app.editgroup_pushbutton.Text = 'Edit Groups Area';
            app.opath_text = uilabel(app.ccg_editor_figure);
            app.opath_text.Tag = 'opath_text';
            app.opath_text.Text = '';
            app.iname_text = uilabel(app.ccg_editor_figure);
            app.iname_text.Tag = 'iname_text';
            app.iname_text.Text = '';
            app.ccg_region_checkbox = uicheckbox(app.ccg_editor_figure);
            app.ccg_region_checkbox.Tag = 'ccg_region_checkbox';
            app.ccg_region_checkbox.Tooltip = {'Double click to select.'};
            app.ccg_region_checkbox.Text = 'CCG Region';
            app.groupnumber_text = uilabel(app.ccg_editor_figure);
            app.groupnumber_text.Tag = 'groupnumber_text';
            app.groupnumber_text.Text = 'Active Group:';
            app.group_coloredit = uicolorpicker(app.ccg_editor_figure);
            app.group_coloredit.Value = [0 0 0];
            app.group_coloredit.ValueChangedFcn = createCallbackFcn(app, @group_coloreditValueChanged, true);
            app.group_coloredit.Tag = 'group_coloredit';
            app.group_coloredit.Enable = 'off';
            app.groups_pushbutton = uibutton(app.ccg_editor_figure, 'push');
            app.groups_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @groups_pushbuttonPushed, true);
            app.groups_pushbutton.Tag = 'groups_pushbutton';
            app.groups_pushbutton.Enable = 'off';
            app.groups_pushbutton.Text = 'Groups';
            app.RemovesmalloffcentermasksCheckBox = uicheckbox(app.ccg_editor_figure);
            app.RemovesmalloffcentermasksCheckBox.Tag = 'checkmasks_checkbox';
            app.RemovesmalloffcentermasksCheckBox.Enable = 'off';
            app.RemovesmalloffcentermasksCheckBox.Text = 'Remove small/off-center masks.';
            app.RemovesmalloffcentermasksCheckBox.Value = true;
            app.masks_pushbutton = uibutton(app.ccg_editor_figure, 'push');
            app.masks_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @masks_pushbuttonButtonPushed, true);
            app.masks_pushbutton.Tag = 'masks_pushbutton';
            app.masks_pushbutton.Enable = 'off';
            app.masks_pushbutton.Text = 'Masks';
            app.gallerymode_checkBox = uicheckbox(app.ccg_editor_figure);
            app.gallerymode_checkBox.ValueChangedFcn = createCallbackFcn(app, @gallerymode_checkBoxValueChanged, true);
            app.gallerymode_checkBox.Tag = 'gallery_checkbox';
            app.gallerymode_checkBox.Text = 'Gallery Mode';
            app.featurethreshold_pushbutton = uibutton(app.ccg_editor_figure, 'push');
            app.featurethreshold_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @featurethreshold_pushbuttonButtonPushed, true);
            app.featurethreshold_pushbutton.Tag = 'featurethreshold_pushbutton';
            app.featurethreshold_pushbutton.Enable = 'off';
            app.featurethreshold_pushbutton.Text = {'Threshold'; 'Feature'};
            app.featureview_pushbutton = uibutton(app.ccg_editor_figure, 'push');
            app.featureview_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @featureview_pushbuttonButtonPushed, true);
            app.featureview_pushbutton.Tag = 'featureview_pushbutton';
            app.featureview_pushbutton.Enable = 'off';
            app.featureview_pushbutton.Text = 'Feature View';
            % Show the figure after all components are created
            app.ccg_editor_figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCG_Editor_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ccg_editor_figure)

                % Execute the startup function
                runStartupFcn(app, @(app)CMG_Editor_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ccg_editor_figure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ccg_editor_figure)
        end
    end
end